classdef analysis_FNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TimelagselectionSwitch        matlab.ui.control.Switch
        TimelagselectionSwitchLabel   matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        EvaluatealldimensionsSwitch   matlab.ui.control.Switch
        EvaluatealldimensionsSwitchLabel  matlab.ui.control.Label
        AToleranceSpinner             matlab.ui.control.Spinner
        AToleranceSpinnerLabel        matlab.ui.control.Label
        RToleranceSpinner             matlab.ui.control.Spinner
        RToleranceSpinnerLabel        matlab.ui.control.Label
        SaveResultsandExitButton      matlab.ui.control.Button
        Log                           matlab.ui.control.TextArea
        UITable                       matlab.ui.control.Table
        MaximumDimensionSpinner       matlab.ui.control.Spinner
        MaximumDimensionSpinnerLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        FalseNearestNeighborsAnalysisLabel  matlab.ui.control.Label
        ProcessButton                 matlab.ui.control.Button
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = [newText{1}];
                case '002'
                    text{length(text) + 1,1} = 'analysis complete';
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            app.FalseNearestNeighborsAnalysisLabel.Text = analysis;

            setappdata(app.UITable, 'data', data)
            
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            
            data = getappdata(app.UITable, 'data');

            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'processed';
            end

            maxDim = app.MaximumDimensionSpinner.Value;
            rTol = app.RToleranceSpinner.Value;
            aTol = app.AToleranceSpinner.Value;
            switch app.EvaluatealldimensionsSwitch.Value
                case 'Yes'
                    speed = 0;
                case 'No'
                    speed = 1;
            end
            
            % Get the field names of all the files that were loaded.
            files = fieldnames(data.(type));
            for i = 1:length(files)
                % Get the fieldnames for all the signals that were
                % contained within that file. This could be different for
                % each file so it is within the loop.
                objs = fieldnames(data.(type).(files{i}));
                for j = 1:length(objs)

                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);

                    for k = 1:length(sigs)

                        for ii = 1:size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2)

                            switch app.TimelagselectionSwitch.Value
                                case 'First Minimum'
                                    tau = data.res.TimeLag.(files{i}).(objs{j}).(sigs{k}).tau1st{ii}(1,1);
                                case '1 Fifth Value'
                                    tau = data.res.TimeLag.(files{i}).(objs{j}).(sigs{k}).tau15th{ii}(end,1);
                            end

                            if isnan(tau)
                                data.res.FNN.(files{i}).(objs{j}).(sigs{k}).dim{ii} = NaN;
                                data.res.FNN.(files{i}).(objs{j}).(sigs{k}).dE{ii} = NaN;
                            else
                                [dim, dE] = FNN(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:,ii), tau, maxDim, rTol, aTol, speed);
                                data.res.FNN.(files{i}).(objs{j}).(sigs{k}).dim{ii} = dim;
                                data.res.FNN.(files{i}).(objs{j}).(sigs{k}).dE{ii} = dE;
                            end

                        end
                    end

                    printLog(app, '001', {['finished object ' files{i} ' ' objs{j}]})

                end
            end

            printLog(app, '002')

            setappdata(app.UITable, 'data', data)

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.EvaluatealldimensionsSwitch.Value;
            
            switch value
                case 'Time Lag'
                    app.MaximumDimensionSpinner.Enable = 'on';
                case 'AMI'
                    app.MaximumDimensionSpinner.Enable = 'off';
            end

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UITable, 'data');
            analysis = app.FalseNearestNeighborsAnalysisLabel.Text;
            
            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.FalseNearestNeighborsAnalysisLabel = uilabel(app.UIFigure);
            app.FalseNearestNeighborsAnalysisLabel.Text = 'False Nearest Neighbors Analysis';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.MaximumDimensionSpinnerLabel = uilabel(app.UIFigure);
            app.MaximumDimensionSpinnerLabel.Text = 'Maximum Dimension';
            app.MaximumDimensionSpinner = uispinner(app.UIFigure);
            app.MaximumDimensionSpinner.Limits = [1 Inf];
            app.MaximumDimensionSpinner.RoundFractionalValues = 'on';
            app.MaximumDimensionSpinner.Value = 12;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Log = uitextarea(app.UIFigure);
            app.Log.Editable = 'off';
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.RToleranceSpinnerLabel = uilabel(app.UIFigure);
            app.RToleranceSpinnerLabel.Text = 'R Tolerance';
            app.RToleranceSpinner = uispinner(app.UIFigure);
            app.RToleranceSpinner.Limits = [1 Inf];
            app.RToleranceSpinner.Value = 15;
            app.AToleranceSpinnerLabel = uilabel(app.UIFigure);
            app.AToleranceSpinnerLabel.Text = 'A Tolerance';
            app.AToleranceSpinner = uispinner(app.UIFigure);
            app.AToleranceSpinner.Limits = [1 Inf];
            app.AToleranceSpinner.Value = 2;
            app.EvaluatealldimensionsSwitchLabel = uilabel(app.UIFigure);
            app.EvaluatealldimensionsSwitchLabel.Text = 'Evaluate all dimensions?';
            app.EvaluatealldimensionsSwitch = uiswitch(app.UIFigure, 'slider');
            app.EvaluatealldimensionsSwitch.Items = {'Yes', 'No'};
            app.EvaluatealldimensionsSwitch.Value = 'Yes';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.Value = 'Raw';
            app.TimelagselectionSwitchLabel = uilabel(app.UIFigure);
            app.TimelagselectionSwitchLabel.Text = 'Time lag selection?';
            app.TimelagselectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.TimelagselectionSwitch.Items = {'First Minimum', '1 Fifth Value'};
            app.TimelagselectionSwitch.Value = 'First Minimum';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_FNN(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end