classdef analysis_RQA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MaximumdatalengthtoprocessSpinner  matlab.ui.control.Spinner
        MaximumdatalengthtoprocessSpinnerLabel  matlab.ui.control.Label
        Version                         matlab.ui.control.Label
        SelectthesignalstoprocessListBox  matlab.ui.control.ListBox
        SelectthesignalstoprocessListBoxLabel  matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        TimelagselectionSwitch          matlab.ui.control.Switch
        TimelagselectionSwitchLabel     matlab.ui.control.Label
        SaveperiodicallyandskipexistingSwitch  matlab.ui.control.Switch
        SaveperiodicallyandskipexistingSwitchLabel  matlab.ui.control.Label
        SetValueSpinner                 matlab.ui.control.Spinner
        SetValueSpinnerLabel            matlab.ui.control.Label
        EmbeddingDimensionDropDown      matlab.ui.control.DropDown
        EmbeddingDimensionDropDownLabel  matlab.ui.control.Label
        TargetValueSpinner              matlab.ui.control.Spinner
        TargetValueSpinnerLabel         matlab.ui.control.Label
        TargetParameterSwitch           matlab.ui.control.Switch
        TargetParameterSwitchLabel      matlab.ui.control.Label
        NormalizationTypeDropDown       matlab.ui.control.DropDown
        NormalizationTypeDropDownLabel  matlab.ui.control.Label
        ZScoreSwitch                    matlab.ui.control.Switch
        ZScoreSwitchLabel               matlab.ui.control.Label
        RQAMethodDropDown               matlab.ui.control.DropDown
        RQAMethodDropDownLabel          matlab.ui.control.Label
        SaveResultsandExitButton        matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        RecurranceQuantificationAnalysisLabel  matlab.ui.control.Label
        ProcessButton                   matlab.ui.control.Button
    end


    properties (Access = private)
        BAR_App   % Main app object
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of
            %           the error and organize the errors available.
            %         - newText, a string that may be inserted into the
            %                    message.
            % Remarks
            % - This method will print messages to the log of the BAR App.
            %   The error codes are unique to each message so they can be
            %   found and referenced easier.
            % - The method is public and is acessible outside of the BAR
            %   App. It is used in some of the example analysis and process
            %   scripts.
            % Future Work
            % - This log should be printed and saved to a file location
            %   each time the BAR App is run.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text of the log.
            text = app.BAR_App.Log.Value;
            % Match the code with the message.
            switch code
                case '001'
                    text{length(text) + 1,1} = ['RQA-001 ' newText];
                case '002'
                    text{length(text) + 1,1} = ['RQA-002 analysis complete'];
                case '003'
                    text{length(text) + 1,1} = ['RQA-003 analysis failed on: ' newText];
                case '004'
                    text{length(text) + 1,1} = ['RQA-004 ' newText];
            end
            % Add the message to the log.
            app.BAR_App.Log.Value = text;
            % Scroll down so the message is visible.
            scroll(app.BAR_App.Log,'bottom')
            % Make sure the app updates.
            drawnow
        end

        function updatelist(app)
            % updatelist(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method updates the signal list within the app with the
            %   currently selected data type.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the app.
            data = getappdata(app.UITable, 'data');
            % Find which data type was selected.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end
            % Initialize the list with an 'all' option.
            sigsAll = {'all'};
            % Get the file names.
            files = fieldnames(data.(type));
            % Iterate through the file names.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects
                for j = 1:length(objs)
                    % Append the signals to the list.
                    sigsAll = [sigsAll; fieldnames(data.(type).(files{i}).(objs{j}).data)];
                end
            end
            % Set the unique signals to the list box.
            app.SelectthesignalstoprocessListBox.Items = unique(sigsAll);

        end

        function dataTable = adjustExport(app, dataTable)
            % dataTable = adjustExport(app, dataTable)
            % inputs  - app, required mlapp object.
            %         - dataTable, the structure that will be adjusted for
            %         variable length before being viewable in the app.
            % output  - dataTable, the same structure adjusted for variable
            %                      length.
            % Remarks
            % - This method adjusts the structure that will be set as the
            %   table data in the app for variable lengths.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % It's possible not all rows will have the same
            % data, all the fields of the structure need to be
            % the same size before it can be made into a table.
            % Firstm find the largest field.
            exportfields = fieldnames(dataTable);
            N = zeros(length(exportfields), 1);
            for j = 1:length(exportfields)
                N(j) = length(dataTable.(exportfields{j}));
            end
            % Padd all the fields with empty arrays if they do
            % not match the maximum.
            for j = 1:length(exportfields)
                if length(dataTable.(exportfields{j})) < max(N)
                    dataTable.(exportfields{j})(length(dataTable.(exportfields{j})):max(N)) = {[]};
                end
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data, analysis)
            % StartupFcn(app, mainapp, data, analysis)
            % inputs  - app, required mlapp object.
            %         - mlapp, BAR App objects.
            %         - data, BAR App data structure.
            %         - analysis, string of the analysis name.
            % Remarks
            % - This is the startup method that initiallized the app.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            % Save the data into the module.
            setappdata(app.UITable, 'data', data)
            % Initialize the signal list in the app.
            updatelist(app)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.BAR_App.Version.Text;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            % ProcessButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This method starts the analysis method. It pulls much of
            %   the primary and secondary inputs from the app interface.
            %   These have default values but can be changed by the user.
            % - Error codes are in the RQAResults integer. Here is what
            %   they stand for: 0 = RQA failed, 1 = RQA was successful, 2 =
            %   the minimum data length was exceeded, 3 = time lag is NaN,
            %   4 = no time lag analysis.
            % Future Work
            % - The options for the embedding dimensions could include the
            %   ability to set them by groups instead of by dimension.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the data from the app.
            data = getappdata(app.UITable, 'data');

            % Empty the current table.
            app.UITable.Data = [];

            % Get the selected level of data to process.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
            end

            % Get the type of RQA to run from the module interface.
            typeRQA = app.RQAMethodDropDown.Value;
            % Get the option to zscore the data.
            switch app.ZScoreSwitch.Value
                case 'Yes'
                    zScore = 1;
                case 'No'
                    zScore = 0;
            end
            % Get the type of normalization to use.
            switch app.NormalizationTypeDropDown.Value
                case 'Euclidean'
                    norm = 'euc';
                case 'Maximum Distance'
                    norm = 'max';
                case 'Minimum Distance'
                    norm = 'min';
                case 'No Normalization'
                    norm = 'non';
            end
            % Get the information on whether to use a radius or a rate as
            % the calculation target.
            setPara = app.TargetParameterSwitch.Value;
            setValue = app.TargetValueSpinner.Value;
            % The module will not plot any data.
            plotOption = 0;

            % All the dimensions will need to be retrieved beforehand. Each
            % cell will house a different dimension.
            % Initialize an empty array.
            dims = {};
            % Initialize an index for the array.
            ind = 1;
            % Get the file names.
            files = fieldnames(data.(type));
            % Iterate through the files.
            for i = 1:length(files)
                % Get the object names and remove the informational ones.
                objs = fieldnames(data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                % Iterate through the objects.
                for j = 1:length(objs)
                    % Get the signals from the object.
                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                    % Iterate through the signals.
                    for k = 1:length(sigs)
                        % Check if 'all' or nothing was selected or the current signal matches the selection.
                        if any(contains(sigs, 'all')) || isempty(sigs) || any(strcmp(app.SelectthesignalstoprocessListBox.Value, sigs{k}))
                            % Get the number of dimensions to use.
                            m = size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2);
                            % Iterate through the dimensions.
                            for ii = 1:m
                                % Come up with a tag to reference the
                                % dimensions.
                                if ii < 10
                                    mString = ['00' num2str(ii)];
                                elseif m < 100
                                    mString = ['0' num2str(ii)];
                                elseif m < 1000
                                    mString = num2str(ii);
                                end
                                % Store the dimension from previous results.
                                dims{ii}(ind,1) = data.res.FNN.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).dim;
                            end
                            % Increase the index of the array.
                            ind = ind + 1;
                        end
                    end
                end
            end

            % Set the embedding dimension to use for all the data based on
            % the user selection.
            for i = 1:length(dims)
                switch app.EmbeddingDimensionDropDown.Value
                    case 'Mode'
                        dims{i} = mode(dims{i});
                    case 'Median'
                        dims{i} = median(dims{i});
                    case 'Mean'
                        dims{i} = mean(dims{i}, 'omitnan');
                    case 'Set Value'
                        dims{i} = app.SetValueSpinner.Value;
                end
            end

            % Get the field names of all the files that were loaded.
            files = fieldnames(data.(type));
            ind = 1;
            for i = 1:length(files)

                % Get the fieldnames for all the signals that were
                % contained within that file. This could be different for
                % each file so it is within the loop.
                objs = fieldnames(data.(type).(files{i}));
                % Iterate through the objects.
                for j = 1:length(objs)
                    % Get the signals from the object.
                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                    % Iterate through the signal names.
                    for k = 1:length(sigs)
                        % Check if 'all' or nothing was selected or the current signal matches the selection.
                        if any(contains(sigs, 'all')) || isempty(sigs) || any(strcmp(app.SelectthesignalstoprocessListBox.Value, sigs{k}))

                            % Get the number of dimensions.
                            m = size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2);

                            % Skip time series that are too long for RQA to
                            % handle.
                            if length(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:, ii)) > app.MaximumdatalengthtoprocessSpinner.Value
                                for ii = 1:m
                                    % Create a tag to reference the dimensions by.
                                    if ii < 10
                                        mString = ['00' num2str(ii)];
                                    elseif m < 100
                                        mString = ['0' num2str(ii)];
                                    elseif m < 1000
                                        mString = num2str(ii);
                                    end

                                    data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).RQAresults = 2;

                                    dataTable.File{ind, 1} = files{i};
                                    dataTable.Object{ind, 1} = objs{j};
                                    dataTable.Signal{ind, 1} = sigs{k};
                                    dataTable.Dimension(ind, 1) = ii;
                                    dataTable.TimeLag(ind, 1) = tau;
                                    dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                    dataTable.RQAresults(ind, 1) = data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).RQAresults;

                                    ind = ind + 1;
                                end

                                % Print a message saying which data
                                % failed.
%                                 printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])
                                % Display an error message.
%                                 printLog(app, '004', 'data length exceeds 40,000')

                                continue
                            end

                            % Iterate through the dimensions.
                            for ii = 1:m
                                % Create a tag to reference the dimensions by.
                                if ii < 10
                                    mString = ['00' num2str(ii)];
                                elseif m < 100
                                    mString = ['0' num2str(ii)];
                                elseif m < 1000
                                    mString = num2str(ii);
                                end

                                % The analysis will be skipped if it has been done before.
                                if isfield(data.res, 'RQA') && isfield(data.res.RQA, files{i}) && isfield(data.res.RQA.(files{i}), objs{j}) && isfield(data.res.RQA.(files{i}).(objs{j}), [sigs{k} '_' mString]) && isfield(data.res.RQA.(files{i}).(objs{j}).([sigs{k} '_' mString]), 'RQAresults')
                                    continue
                                end
                                % The time lag selection and pull them from a
                                % previous analysis.
                                if isfield(data.res.TimeLag.(files{i}).(objs{j}).data, [sigs{k} '_' mString]) && isfield(data.res.TimeLag.(files{i}).(objs{j}).data.([sigs{k} '_' mString]), 'tau1st')
                                    switch app.TimelagselectionSwitch.Value
                                        case 'First Minimum'
                                            tau = data.res.TimeLag.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).tau1st(1,1);
                                        case '1 Fifth Value'
                                            tau = data.res.TimeLag.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).tau1_5th(end,1);
                                    end
                                else
                                    data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).RQAresults = 4;

                                    dataTable.File{ind, 1} = files{i};
                                    dataTable.Object{ind, 1} = objs{j};
                                    dataTable.Signal{ind, 1} = sigs{k};
                                    dataTable.Dimension(ind, 1) = ii;
                                    dataTable.TimeLag(ind, 1) = tau;
                                    dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                    dataTable.RQAresults(ind, 1) = data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).RQAresults;

                                    ind = ind + 1;

                                    % Print a message saying which data
                                    % failed.
%                                     printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])
                                    % Display an error message.
%                                     printLog(app, '004', 'no time lag results found')

                                    continue
                                end
                                % If a unique value for the embedding dimension
                                % was selected pull it from the prior values.
                                if strcmp(app.EmbeddingDimensionDropDown.Value, 'Unique')
                                    dims{ii} = data.res.FNN.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).dim;
                                end
                                % If either the time lag or embedding dimension
                                % are NaNs set the new results and table values
                                % to NaNs.
                                if isnan(tau) || isnan(dims{ii})
                                    data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).RQAresults = 3;

                                    dataTable.File{ind, 1} = files{i};
                                    dataTable.Object{ind, 1} = objs{j};
                                    dataTable.Signal{ind, 1} = sigs{k};
                                    dataTable.Dimension(ind, 1) = ii;
                                    dataTable.TimeLag(ind, 1) = tau;
                                    dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                    dataTable.RQAresults(ind, 1) = data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).RQAresults;

                                    ind = ind + 1;

                                    % Print a message saying which data failed.
%                                     printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])
                                    % Display an error message
%                                     printLog(app, '004', 'no time lag results found')

                                    continue
                                else

                                    try

                                        if ~(isfield(data.res, 'RQA') && isfield(data.res.RQA, files{i}) && isfield(data.res.RQA.(files{i}), objs{j}) && isfield(data.res.RQA.(files{i}).(objs{j}).data, [sigs{k} '_' mString]) && isfield(data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]), 'RQAresults')) && strcmp(app.SaveperiodicallyandskipexistingSwitch.Value, 'Yes')

                                            tic
                                            % Run the analysis.
                                            [~, results] = RQA(data.(type).(files{i}).(objs{j}).data.(sigs{k})(:, ii), typeRQA, tau, dims{ii}, zScore, norm, setPara, setValue, plotOption);
                                            t = toc;
                                            % Save the RQA results to the results.
                                            data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).RQAresults = 1;
                                            % Save values to the table structure so the
                                            % user can see them.
                                            dataTable.File{ind, 1} = files{i};
                                            dataTable.Object{ind, 1} = objs{j};
                                            dataTable.Signal{ind, 1} = sigs{k};
                                            dataTable.Dimension(ind, 1) = ii;
                                            dataTable.TimeLag(ind, 1) = tau;
                                            dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                            dataTable.RQAresults(ind, 1) = data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).RQAresults;
                                            % Run a loop to save the values from the
                                            % RQA results.
                                            RQAresults = fieldnames(results);
                                            for jj = 1:length(RQAresults)
                                                % The 'DATA' result will be saved to
                                                % the analysis results.
                                                if ~strcmp(RQAresults{jj}, 'DATA')
                                                    % Save everything else to the
                                                    % results and the table structure.
                                                    data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).(RQAresults{jj}) = results.(RQAresults{jj});
                                                    dataTable.(RQAresults{jj}){ind, 1} = results.(RQAresults{jj});
                                                end
                                            end
                                        % If results already exist pull
                                        % some of the information and put
                                        % it in the table.
                                        else
                                            % Save values to the table structure so the user can see them.
                                            dataTable.File{ind, 1} = files{i};
                                            dataTable.Object{ind, 1} = objs{j};
                                            dataTable.Signal{ind, 1} = sigs{k};
                                            dataTable.Dimension(ind, 1) = ii;
                                            dataTable.TimeLag(ind, 1) = tau;
                                            dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                            dataTable.RQAresults(ind, 1) = data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).RQAresults;
                                        end

                                    catch ME
                                        % If there is an error set all the same
                                        % reults to NaNs.

                                        data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).RQAresults = 0;

                                        dataTable.File{ind, 1} = files{i};
                                        dataTable.Object{ind, 1} = objs{j};
                                        dataTable.Signal{ind, 1} = sigs{k};
                                        dataTable.Dimension(ind, 1) = ii;
                                        dataTable.TimeLag(ind, 1) = tau;
                                        dataTable.EmbeddingDim(ind, 1) = dims{ii};
                                        dataTable.RQAresults(ind, 1) = data.res.RQA.(files{i}).(objs{j}).data.([sigs{k} '_' mString]).RQAresults;

                                        % Print a message saying which data
                                        % failed.
                                        printLog(app, '003', [files{i} ' ' objs{j} ' ' sigs{k}])
                                        % Get MATLAB's last error message and display it.
                                        printLog(app, '004', ME.message)
                                    end

                                end
                                % Increase the index for the table structure.
                                ind = ind + 1;
                                
                            end
                        end
                    end
                end
                % Every 50 files print an update message.
                if rem(i, 50) == 0
                    printLog(app, '001', ['finished ' num2str(i) ' of ' num2str(height(files)) ' files'])
                    % Every 10 files and if the user selected to periodically save the data, the table structure will be displayed in the table and 
                    % saved to the computer automatically.
                    if strcmp(app.SaveperiodicallyandskipexistingSwitch.Value, 'Yes')
                        dataTable = adjustExport(app, dataTable);
                        app.UITable.Data = struct2table(dataTable);
                        app.UITable.ColumnName = fieldnames(dataTable);
                        analysisComplete(app.BAR_App, data, 'RQA', 1)
                    end
                end
            end
            % Print a message complete message to the log.
            printLog(app, '002')
            % Save the data to the module.
            setappdata(app.UITable, 'data', data)
            % Adjust the length of the table structure.
            dataTable = adjustExport(app, dataTable);
            % Sent the table structure to the module's table and set the
            % column names to the fieldnames.
            app.UITable.Data = struct2table(dataTable);
            app.UITable.ColumnName = fieldnames(dataTable);

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This method will send the data back to the BAR App and save
            %   it.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data from the module.
            data = getappdata(app.UITable, 'data');
            % The analysis name that will be used to save the data.
            analysis = 'RQA';
            % Save the data using a BAR_App method.
            analysisComplete(app.BAR_App, data, analysis, 0)
            % Close the app.
            delete(app)
        end

        % Value changed function: TargetParameterSwitch
        function TargetParameterSwitchValueChanged(app, event)
            % TargetParameterSwitchValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - This method controls the users ability to modify values in
            %   the app's interface.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current value of the switch.
            value = app.TargetParameterSwitch.Value;
            switch value
                % If the switch is set to recurrence set a suggested value
                % and the limits.
                case 'Recurrence'
                    app.TargetValueSpinner.Step = 0.1;
                    app.TargetValueSpinner.Limits = [0.1, 100];
                    app.TargetValueSpinner.Value = 2.5;
                    % If the switch is set to Radius set the suggested value
                    % and the limits.
                case 'Radius'
                    app.TargetValueSpinner.Step = 0.001;
                    app.TargetValueSpinner.Limits = [0.001, inf];
                    app.TargetValueSpinner.Value = 1;
            end
        end

        % Value changed function: EmbeddingDimensionDropDown
        function EmbeddingDimensionDropDownValueChanged(app, event)
            % EmbeddingDimensionDropDownValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, an object with information about the app
            %                  from the button press.
            % Remarks
            % - If the user selects that a specific value should be used
            %   for the embedding dimension this will allow the spinner to be
            %   changed.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current value for which embedding dimension to use.
            value = app.EmbeddingDimensionDropDown.Value;
            switch value
                case 'Set Value'
                    % If it is set to 'Set Value' turn the spinner on.
                    app.SetValueSpinner.Enable = 1;
                    app.SetValueSpinnerLabel.Enable = 1;
                otherwise
                    % For an other option turn the spinner off.
                    app.SetValueSpinner.Enable = 0;
                    app.SetValueSpinnerLabel.Enable = 0;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged2(app, event)
            updatelist(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.RecurranceQuantificationAnalysisLabel = uilabel(app.UIFigure);
            app.RecurranceQuantificationAnalysisLabel.Text = 'Recurrance Quantification Analysis';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.RQAMethodDropDownLabel = uilabel(app.UIFigure);
            app.RQAMethodDropDownLabel.Text = 'RQA Method';
            app.RQAMethodDropDown = uidropdown(app.UIFigure);
            app.RQAMethodDropDown.Items = {'RQA', 'cRQA', 'jRQA', 'mdRQA'};
            app.RQAMethodDropDown.Value = 'RQA';
            app.ZScoreSwitchLabel = uilabel(app.UIFigure);
            app.ZScoreSwitchLabel.Text = 'Z Score?';
            app.ZScoreSwitch = uiswitch(app.UIFigure, 'slider');
            app.ZScoreSwitch.Items = {'Yes', 'No'};
            app.ZScoreSwitch.Value = 'Yes';
            app.NormalizationTypeDropDownLabel = uilabel(app.UIFigure);
            app.NormalizationTypeDropDownLabel.Text = 'Normalization Type';
            app.NormalizationTypeDropDown = uidropdown(app.UIFigure);
            app.NormalizationTypeDropDown.Items = {'Euclidean', 'Maximum Distance', 'Minimum Distance', 'No Normalization'};
            app.NormalizationTypeDropDown.Value = 'No Normalization';
            app.TargetParameterSwitchLabel = uilabel(app.UIFigure);
            app.TargetParameterSwitchLabel.Text = 'Target Parameter';
            app.TargetParameterSwitch = uiswitch(app.UIFigure, 'slider');
            app.TargetParameterSwitch.Items = {'Recurrence', 'Radius'};
            app.TargetParameterSwitch.ValueChangedFcn = createCallbackFcn(app, @TargetParameterSwitchValueChanged, true);
            app.TargetParameterSwitch.Value = 'Recurrence';
            app.TargetValueSpinnerLabel = uilabel(app.UIFigure);
            app.TargetValueSpinnerLabel.Text = 'Target Value';
            app.TargetValueSpinner = uispinner(app.UIFigure);
            app.TargetValueSpinner.Step = 0.1;
            app.TargetValueSpinner.Limits = [0.1 100];
            app.TargetValueSpinner.Value = 2.5;
            app.EmbeddingDimensionDropDownLabel = uilabel(app.UIFigure);
            app.EmbeddingDimensionDropDownLabel.Text = 'Embedding Dimension';
            app.EmbeddingDimensionDropDown = uidropdown(app.UIFigure);
            app.EmbeddingDimensionDropDown.Items = {'Unique', 'Mode', 'Mean', 'Median', 'Set Value'};
            app.EmbeddingDimensionDropDown.ValueChangedFcn = createCallbackFcn(app, @EmbeddingDimensionDropDownValueChanged, true);
            app.EmbeddingDimensionDropDown.Value = 'Unique';
            app.SetValueSpinnerLabel = uilabel(app.UIFigure);
            app.SetValueSpinnerLabel.Enable = 'off';
            app.SetValueSpinnerLabel.Text = 'Set Value';
            app.SetValueSpinner = uispinner(app.UIFigure);
            app.SetValueSpinner.Limits = [1 Inf];
            app.SetValueSpinner.RoundFractionalValues = 'on';
            app.SetValueSpinner.Enable = 'off';
            app.SetValueSpinner.Value = 1;
            app.SaveperiodicallyandskipexistingSwitchLabel = uilabel(app.UIFigure);
            app.SaveperiodicallyandskipexistingSwitchLabel.Text = 'Save periodically and skip existing?';
            app.SaveperiodicallyandskipexistingSwitch = uiswitch(app.UIFigure, 'slider');
            app.SaveperiodicallyandskipexistingSwitch.Items = {'Yes', 'No'};
            app.SaveperiodicallyandskipexistingSwitch.Value = 'Yes';
            app.TimelagselectionSwitchLabel = uilabel(app.UIFigure);
            app.TimelagselectionSwitchLabel.Text = 'Time lag selection?';
            app.TimelagselectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.TimelagselectionSwitch.Items = {'First Minimum', '1 Fifth Value'};
            app.TimelagselectionSwitch.Value = 'First Minimum';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged2, true);
            app.Switch.Value = 'Raw';
            app.SelectthesignalstoprocessListBoxLabel = uilabel(app.UIFigure);
            app.SelectthesignalstoprocessListBoxLabel.Text = 'Select the signals to process';
            app.SelectthesignalstoprocessListBox = uilistbox(app.UIFigure);
            app.SelectthesignalstoprocessListBox.Items = {};
            app.SelectthesignalstoprocessListBox.Multiselect = 'on';
            app.SelectthesignalstoprocessListBox.Value = {};
            app.Version = uilabel(app.UIFigure);
            app.MaximumdatalengthtoprocessSpinnerLabel = uilabel(app.UIFigure);
            app.MaximumdatalengthtoprocessSpinnerLabel.Text = 'Maximum data length to process';
            app.MaximumdatalengthtoprocessSpinner = uispinner(app.UIFigure);
            app.MaximumdatalengthtoprocessSpinner.Step = 100;
            app.MaximumdatalengthtoprocessSpinner.Limits = [100 Inf];
            app.MaximumdatalengthtoprocessSpinner.RoundFractionalValues = 'on';
            app.MaximumdatalengthtoprocessSpinner.ValueDisplayFormat = '%.0f';
            app.MaximumdatalengthtoprocessSpinner.Value = 40000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis_RQA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end