classdef BAR_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        v10Label                        matlab.ui.control.Label
        BiomechanicsAnalysisandReportingApplicationLabel  matlab.ui.control.Label
        Log                             matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        DatabaseSearchTab               matlab.ui.container.Tab
        Directory                       matlab.ui.control.TextArea
        MergeButton                     matlab.ui.control.Button
        CopyButton                      matlab.ui.control.Button
        MoveButton                      matlab.ui.control.Button
        ExcludeLabel                    matlab.ui.control.Label
        IncludeLabel                    matlab.ui.control.Label
        SearchTagsExclude               matlab.ui.control.EditField
        IdentifyGroupsButton            matlab.ui.control.Button
        Database                        matlab.ui.control.EditField
        SelectWorkingDatabaseButton     matlab.ui.control.Button
        SaveCurrentDataButton           matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        FindButton                      matlab.ui.control.Button
        TableFiles                      matlab.ui.control.Table
        SearchTagsInclude               matlab.ui.control.EditField
        EditFieldLabel                  matlab.ui.control.Label
        EquipmentDropDown               matlab.ui.control.DropDown
        SelectequipmentthedatawasrecordedfromLabel  matlab.ui.control.Label
        FileExtensionDropDown           matlab.ui.control.DropDown
        SelectfileextensionofthedatafilesLabel  matlab.ui.control.Label
        AddDirectorytoSearchButton      matlab.ui.control.Button
        ProcessingTab                   matlab.ui.container.Tab
        ProcessButton                   matlab.ui.control.Button
        SelectProcessingModuleDropDown  matlab.ui.control.DropDown
        SelectProcessingModuleDropDownLabel  matlab.ui.control.Label
        AnalysisTab                     matlab.ui.container.Tab
        RunAnalysisButton               matlab.ui.control.Button
        AnalysisDropDown                matlab.ui.control.DropDown
        AnalysisDropDownLabel           matlab.ui.control.Label
        ReviewTab                       matlab.ui.container.Tab
        ReviewDropDown                  matlab.ui.control.DropDown
        AnalysisDropDown_2Label         matlab.ui.control.Label
        ReviewAnalysisButton            matlab.ui.control.Button
        StatisticsTab                   matlab.ui.container.Tab
        ExportTab                       matlab.ui.container.Tab
        ExportMeansSwitch               matlab.ui.control.Switch
        ExportMeansSwitchLabel          matlab.ui.control.Label
        SelectitemstoexportListBox      matlab.ui.control.ListBox
        SelectitemstoexportListBoxLabel  matlab.ui.control.Label
        FindeligibleexportsButton       matlab.ui.control.Button
        ExportResultsButton             matlab.ui.control.Button
        FormatsDropDown                 matlab.ui.control.DropDown
        FormatsDropDownLabel            matlab.ui.control.Label
        UITableExport                   matlab.ui.control.Table
        ButtonFormatExport              matlab.ui.control.Button
        ConfigurationTab                matlab.ui.container.Tab
        FilesAnalysis                   matlab.ui.control.EditField
        ProcessLabel_2                  matlab.ui.control.Label
        FilesProcess                    matlab.ui.control.EditField
        ProcessLabel                    matlab.ui.control.Label
        MethodDirectoriesLabel          matlab.ui.control.Label
        UpdateConfigurationFileButton   matlab.ui.control.Button
        FilesLoad                       matlab.ui.control.EditField
        LoadingscriptsLabel             matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        AppAnalysis % Description
    end
    
    methods (Access = private)
        
        function files = filesearch(app, files, m_directory)

            m_files = dir(m_directory);
            m_tags_include = app.SearchTagsInclude.Value;
            m_tags_exclude = app.SearchTagsExclude.Value;
            if ~isempty(m_tags_include)
                m_tags_include = textscan(m_tags_include,'%s','Delimiter',',');
            end
            if ~isempty(m_tags_exclude)
                m_tags_exclude = textscan(m_tags_exclude,'%s','Delimiter',',');
            end

            for i = 3:length(m_files)
                if isfolder([m_files(i).folder '\' m_files(i).name]) && ~strcmp(m_files(i).name(1), '.')
                    files = filesearch(app, files, [m_files(i).folder '\' m_files(i).name]);
                elseif isfile([m_files(i).folder '\' m_files(i).name])
                    if isempty(fieldnames(files))
                        n = 0;
                        files.name = {};
                        files.folder = {};
                        files.bytes = [];
                    else
                        n = length(files.name);
                    end
                    [~, ~, ext] = fileparts(m_files(i).name);
                    % Check if there are tags available and if they are
                    % determine which files have them.
                    if ~isempty(m_tags_include)
                        bol_include = cellfun(@(s)find(~cellfun('isempty', strfind({m_files(i).name},s))),m_tags_include{1},'uni',0);
                    else
                        bol_include = '';
                    end
                    % Determine if there are tags available and if they are
                    % determine which files do not have them.
                    if ~isempty(m_tags_exclude)
                        bol_exclude = cellfun(@(s)find(~cellfun('isempty', strfind({m_files(i).name},s))),m_tags_exclude{1},'uni',0);
                    else
                        bol_exclude = '';
                    end
                    % If the file matches the selected file extension and
                    % the tag requirements it will be added to the table.
                    if (strcmp(ext, ['.' app.FileExtensionDropDown.Value]) && ((strcmp(m_tags_include, '') || ~any(cellfun(@isempty,bol_include))) && (strcmp(m_tags_exclude, '') || ~any(~cellfun(@isempty,bol_exclude)))))
                        files.name{n+1, 1} = m_files(i).name;
                        files.folder{n+1, 1} = m_files(i).folder;
                        files.bytes(n+1, 1) = m_files(i).bytes/1000;
                    end
                end
                % Every ten files update the table.
                if rem(length(m_files),10) == 0
                    app.TableFiles.Data = struct2table(files);
                    drawnow
                    pause(0.01)
                end
            end
            
        end
        
        function dataExport = formatBUCaltest(app, data)
            
            files = fieldnames(data.raw);
            for i = 1:length(files)
                measures = fieldnames(data.raw.(files{i}));
                for j = 1:length(measures)
                    ind3 = strfind(measures{j}, '_');
                    if ind3(1) > 4
                        ind3 = [4, ind3];
                    end
                    if strcmp(measures{j}(ind3(1) + 1), 'M')
                        forcePlates{j,1} = measures{j}(1:3);
                    else
                        forcePlates{j,1} = measures{j}(1:ind3(1)-1);
                    end
                end
                forcePlates = unique(forcePlates);
                for j = 1:length(forcePlates)
                    if ~contains(forcePlates{j},'FP')
                        continue
                    end
                    ind = 1;
                    trials = {};
                    for k = 1:length(measures)
                        if contains(measures{k},forcePlates{j})
                            ind3 = strfind(measures{k}, '_');
                            if ind3(1) > 4
                                ind3 = [4, ind3];
                            end
                            if strcmp(measures{k}(ind3(1) + 1), 'M')
                                trials{ind, 1} = measures{k}(4:ind3(1)-1);
                            else
                                trials{ind,1} = measures{k}(ind3(1)+1:ind3(2)-1);
                            end
                            ind = ind + 1;
                        end
                    end
                    trials = unique(trials);
                    trialsN = [1:length(trials)]';
                    for k = 1:length(trials)
                        ind4 = [];
                        switch trialsN(k)
                            case 1
                                ind4 = 0;
                            case 2
                                ind4 = 8;
                            case 3
                                ind4 = 16;
                            case 4
                                ind4 = 24;
                            case 5
                                ind4 = 32;
                        end

                        if i == 1
                            dataExport.(forcePlates{j}){1, ind4 + 1} = trialsN(k);
                            dataExport.(forcePlates{j}){1, 41} = 'Force Orientation Error';
                            dataExport.(forcePlates{j}){2, ind4 + 1} = 'Date';
                            dataExport.(forcePlates{j}){2, ind4 + 2} = 'xmean';
                            dataExport.(forcePlates{j}){2, ind4 + 3} = 'xsd';
                            dataExport.(forcePlates{j}){2, ind4 + 4} = 'ymean';
                            dataExport.(forcePlates{j}){2, ind4 + 5} = 'ysd';
                            dataExport.(forcePlates{j}){2, ind4 + 6} = 'zmean';
                            dataExport.(forcePlates{j}){2, ind4 + 7} = 'zsd';
                            dataExport.(forcePlates{j}){2, 41} = 'Date';
                            dataExport.(forcePlates{j}){2, 42 + 2*(k - 1)} = [num2str(trialsN(k)) 'mean'];
                            dataExport.(forcePlates{j}){2, 42 + 2*(k - 1) + 1} = [num2str(trialsN(k)) 'sd'];
                        end
                        dataExport.(forcePlates{j}){i + 2, ind4 + 1} = datestr(datetime(files{i}(4:end),'InputFormat','yyyyMMdd'));

                        if k == 1
                            dataExport.(forcePlates{j}){i + 2, 41} = datestr(datetime(files{i}(4:end),'InputFormat','yyyyMMdd'));
                        end

                        try
                            dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(1)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(1)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(2)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(2)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(3)*1000;
                            dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(3)*1000;
                            dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_OrientationError_MEAN']).data.METRIC(1);
                            dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '_CALTESTER_CT_OrientationError_SD']).data.METRIC(1);
                        catch
                            try
                                dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_MEAN']).data.METRIC(1)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_SD']).data.METRIC(1)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_MEAN']).data.METRIC(2)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_SD']).data.METRIC(2)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_MEAN']).data.METRIC(3)*1000;
                                dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_TipDiff_SD']).data.METRIC(3)*1000;
                                dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_OrientationError_MEAN']).data.METRIC(1);
                                dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '_' trials{k} '__CALTESTER_CT_OrientationError_SD']).data.METRIC(1);
                            catch
                                try
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_OrientationError_MEAN']).data.METRIC(1);
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} trials{k} '_CALTESTER_CT_OrientationError_SD']).data.METRIC(1);
                                catch
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 2} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 3} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(1)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 4} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 5} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(2)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 6} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_MEAN']).data.METRIC(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, ind4 + 7} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_TipDiff_SD']).data.METRIC(3)*1000;
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1)} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_OrientationError_MEAN']).data.METRIC(1);
                                    dataExport.(forcePlates{j}){i + 2, 42 + 2*(k - 1) + 1} = data.raw.(files{i}).([forcePlates{j} '0' trials{k} '_CALTESTER_CT_OrientationError_SD']).data.METRIC(1);
                                end
                            end
                        end
                    end
                end
            end

        end
        
        function createDatabase(app)
            m_path = app.Database.Value;

            folders = dir(m_path);

            if ~any(contains({folders.name}, 'Results'))
                mkdir([m_path '\Results'])
                printLog(app, '010', [m_path '\Results'])
            end
            if ~any(contains({folders.name}, 'Figures'))
                mkdir([m_path '\Figures'])
                printLog(app, '011', [m_path '\Figures'])
            end
            if ~any(contains({folders.name}, 'Export'))
                mkdir([m_path '\Export'])
                printLog(app, '011', [m_path '\Export'])
            end

            config = loadConfig(app);
            config.database = m_path;
            saveConfig(app, config);
            
        end
        
        function identifyFileTypes(app)
            % Load the existing configuration so it can be updated.
            config = loadConfig(app);

            % Use the directory entered into the app to find the load 
            % scripts.
            m_files = dir(app.FilesLoad.Value);
            % Update the configuration with the directory entered into the 
            % app.
            config.options.filesload = app.FilesLoad.Value;

            % Empty the available file types from the saved configuration.
            config.options.filetypes = [];

            % Look for files within the directory. If the file names
            % contain the word 'load' identify the file extension and the
            % format of the data it intends to load.
            for i = 1:height(m_files)
                % MATLAB creates temporary asv files of m-files that are
                % currently being edited. There are not viable options.
                if contains(m_files(i).name, 'load') && ~contains(m_files(i).name, 'asv')
                    parts = textscan(m_files(i).name, '%s%s%s', 'Delimiter', '_');
                    if isfield(config.options.filetypes, parts{2}{1})
                        n = length(config.options.filetypes.(parts{2}{1})) + 1;
                    else
                        n = 1;
                    end
                    config.options.filetypes.(parts{2}{1}){n} = parts{3}{1}(1:end-2);
                end
            end
            
            % If there are no load files or if the directory is misleading
            % because files have been moved or the app was moved nothing
            % will be found. The object in the app can't be set to empty.
            if ~isempty(config.options.filetypes)
                app.FileExtensionDropDown.Items = fieldnames(config.options.filetypes);
            end

            % Save the configuration with the updated directory and or file
            % extensions and formats.
            saveConfig(app, config)
            
            % Run the callback for the file extension selection object in
            % the app so the format selection object displays the correct
            % options.
            app.FileExtensionDropDownValueChanged;

        end

        function identifyProcessOptions(app)
            % Load the existing configuration so it can be updated.
            config = loadConfig(app);

            % Use the directory entered into the app to find the load 
            % scripts.
            m_files = dir(app.FilesProcess.Value);
            % Update the configuration with the directory entered into the 
            % app.
            config.options.filesprocess = app.FilesProcess.Value;

            % Empty the available treatment files from the saved 
            % configuration.
            config.options.process = [];

            % Look for files within the directory. If the file names
            % contain the word 'process' save them within the app and 
            % configuration.
            ind = 1;
            for i = 1:height(m_files)
                % MATLAB creates temporary asv files of m-files that are
                % currently being edited. There are not viable options.
                if contains(m_files(i).name, 'process') && ~contains(m_files(i).name, 'asv')
                    [~, name] = fileparts(m_files(i).name);
                    parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                    config.options.process{ind} = parts{2}{1};
                    ind = ind + 1;
                end
            end

            % It is assumed the files will have the format
            % "process_<Processing Name>....." So there will be duplicates
            % for options within the processing apps. Such as
            % process_Treatment_mlapp and process_Treatment_LowPass.mlapp.
            config.options.process = unique(config.options.process);
            
            % If there are no load files or if the directory is misleading
            % because files have been moved or the app was moved nothing
            % will be found. The object in the app can't be set to empty.
            if ~isempty(config.options.process)
                app.SelectProcessingModuleDropDown.Items = config.options.process;
            end

            % Save the configuration with the updated directory and or file
            % extensions and formats.
            saveConfig(app, config)
            
        end
        
        
        function identifyAnalysisOptions(app)
            % Load the existing configuration so it can be updated.
            config = loadConfig(app);

            % Use the directory entered into the app to find the load 
            % scripts.
            m_files = dir(app.FilesAnalysis.Value);
            % Update the configuration with the directory entered into the 
            % app.
            config.options.filesanalysis = app.FilesAnalysis.Value;

            % Empty the available treatment files from the saved 
            % configuration.
            config.options.analysis = [];

            % Look for files within the directory. If the file names
            % contain the word 'analysis' save them within the app and 
            % configuration.
            ind = 1;
            for i = 1:height(m_files)
                % MATLAB creates temporary asv files of m-files that are
                % currently being edited. There are not viable options.
                if contains(m_files(i).name, 'analysis') && ~contains(m_files(i).name, 'asv')
                    [~, name] = fileparts(m_files(i).name);
                    parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                    config.options.analysis{ind} = parts{2}{1};
                    ind = ind + 1;
                end
            end

            % If there are no load files or if the directory is misleading
            % because files have been moved or the app was moved nothing
            % will be found. The object in the app can't be set to empty.
            if ~isempty(config.options.analysis)
                app.AnalysisDropDown.Items = config.options.analysis;
            end

            % Save the configuration with the updated directory and or file
            % extensions and formats.
            saveConfig(app, config)
            
        end
        
        function dat = groupAuto(app, level, dat)

            switch level
                case 'filename'
                    dat = split(dat, '\');
                    i = 1;
                    while i <= length(dat)
                        temp = split(dat{i}, '_');
                        if length(temp) > 1
                            dat = [dat(1:i-1); temp; dat(i+1:end)];
                        end
                        i = i + 1;
                    end
                    ind = strfind(dat{end}, '.');
                    dat{end}(ind(end):end) = [];
                case 'object'
                    files = fieldnames(dat.raw);
                    for i = 1:length(files)
                        obj = fieldnames(dat.raw.(files{i}));
                        obj(strcmp(obj, 'meta')) = [];
                        obj(strcmp(obj, 'groups')) = [];
                        for j = 1:length(obj)
                            dat.raw.(files{i}).(obj{j}).groups = split(obj{j}, '_');
                        end
                    end
            end
            
        end
    end
    
    methods (Access = public)

        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['001 file search started'];
                case '002'
                    text{length(text) + 1,1} = ['002 file search complete, ' num2str(newText{1}) ' files found, ' num2str(ceil(newText{2}*1e-6)) ' MB total'];
                case '003'
                    text{length(text) + 1,1} = ['003 error reading file: ' newText];
                case '004'
                    text{length(text) + 1,1} = ['004 loading files started for: ' newText];
                case '005'
                    text{length(text) + 1,1} = ['005 loading files complete for: ' newText];
                case '006'
                    text{length(text) + 1,1} = ['006 results not found'];
                case '007'
                    text{length(text) + 1,1} = ['007 results exported'];
                case '008'
                    text{length(text) + 1,1} = ['008 results formated for export and displayed in table'];
                case '009'
                    text{length(text) + 1,1} = ['009 results formated for exported but are not compatible with the table'];
                case '010'
                    text{length(text) + 1,1} = ['010 created Results folder at ' newText];
                case '011'
                    text{length(text) + 1,1} = ['011 created Figure folder at ' newText];
                case '012'
                    text{length(text) + 1,1} = ['012 files saving....'];
                case '013'
                    text{length(text) + 1,1} = ['013 files saved'];
                case '014'
                    text{length(text) + 1,1} = ['014 moving files...'];
                case '015'
                    text{length(text) + 1,1} = ['015 moving files complete'];
                case '016'
                    text{length(text) + 1,1} = ['016 no files found in the selected directory'];
                case '017'
                    text{length(text) + 1,1} = ['017 error moving file: ' newText];
                case '018'
                    text{length(text) + 1,1} = ['018 copying files...'];
                case '019'
                    text{length(text) + 1,1} = ['019 copying files complete'];
                case '020'
                    text{length(text) + 1,1} = ['020 error copying file: ' newText];
                case '021'
                    text{length(text) + 1,1} = ['021 ' newText];
                case '022'
                    text{length(text) + 1,1} = ['022 starting analysis:' newText];
                case '023'
                    text{length(text) + 1,1} = ['022 analysis complete:' newText];
                case '024'
                    text{length(text) + 1,1} = ['024 ' newText];
                case '025'
                    text{length(text) + 1,1} = ['025 saving data from ' newText];
                case '026'
                    text{length(text) + 1,1} = ['026 data saved from ' newText];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
            
        end
        
        function analysisComplete(app, data, analysis)

            printLog(app, '025', analysis)

            setappdata(app.TableFiles, 'data', data);
            
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            m_resultsPath = app.Database.Value;
            save([m_resultsPath '\Results\' analysis timeStamp], 'data', '-v7.3')

            printLog(app, '026', analysis)

        end
        
        function dataExport = formatResults(app, data)
            
            types = app.SelectitemstoexportListBox.Value;

            if ~iscell(types)
                types = {types};
            end

            dataExport = struct;

            for i = 1:length(types)

                switch types{i}
                    case {'raw', 'res'}
                        ind = 1;
                        files = fieldnames(data.(types{i}));
                        for j = 1:length(files)
                            measures = fieldnames(data.(types{i}).(files{j}));
                            measures(strcmp(measures, 'meta')) = [];
                            measures(strcmp(measures, 'groups')) = [];
                            for k = 1:length(measures)
                                sigs = fieldnames(data.(types{i}).(files{j}).(measures{k}).data);
                                for ii = 1:length(sigs)

                                    ind2 = 0;

                                    if strcmp(app.ExportMeansSwitch.Value, 'Yes')

                                        dataExport.file{ind, 1} = files{j};
                                        if isfield(data.(types{i}).(files{j}), 'groups')
                                            for jj = 1:length(data.(types{i}).(files{j}).groups)
                                                dataExport.(['group' num2str(jj)]){ind, 1} = data.(types{i}).(files{j}).groups{jj};
                                            end
                                        elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{j}), 'groups')
                                            for jj = 1:length(data.raw.(files{j}).groups)
                                                dataExport.(['group' num2str(jj)]){ind, 1} = data.raw.(files{j}).groups{jj};
                                            end
                                        end

                                        dataExport.measures{ind, 1} = measures{k};
                                        dataExport.(sigs{ii}){ind, 1} = mean(data.(types{i}).(files{j}).(measures{k}).data.(sigs{ii}), 1, 'omitnan');
                                    elseif strcmp(app.ExportMeansSwitch.Value, 'No')
                                        for kk = 1:numel(data.(types{i}).(files{j}).(measures{k}).data.(sigs{ii}))

                                            dataExport.file{ind + ind2, 1} = files{j};
                                            if isfield(data.(types{i}).(files{j}), 'groups')
                                                for jj = 1:length(data.(types{i}).(files{j}).groups)
                                                    dataExport.(['group' num2str(jj)]){ind + ind2, 1} = data.(types{i}).(files{j}).groups{jj};
                                                end
                                            elseif ~strcmp(types{i}, 'raw') && isfield(data.raw.(files{j}), 'groups')
                                                for jj = 1:length(data.raw.(files{j}).groups)
                                                    dataExport.(['group' num2str(jj)]){ind + ind2, 1} = data.raw.(files{j}).groups{jj};
                                                end
                                            end

                                            dataExport.measures{ind + ind2, 1} = measures{k};
                                            dataExport.(sigs{ii}){ind + ind2, 1} = data.(types{i}).(files{j}).(measures{k}).data.(sigs{ii})(kk);

                                            ind2 = ind2 + 1;

                                        end
                                    end
                                end
                                ind = ind + ind2;
                            end

                            if rem(j, 10) == 0
                                printLog(app, '021', ['formated ' num2str(j) ' of ' num2str(length(files))])
                            end

                        end

                        N = [];
                        exportfields = fieldnames(dataExport);
                        for j = 1:length(exportfields)
                            N(j) = length(dataExport.(exportfields{j}));
                        end
                        for j = 1:length(exportfields)
                            if length(dataExport.(exportfields{j})) < max(N)
                                dataExport.(exportfields{j})(length(dataExport.(exportfields{j})):max(N)) = {[]};
                            end
                        end


%                     case 'res'
% 
%                         ind = 1;
%                         analysis = fieldnames(data.res);
%                         for j = 1:length(analysis)
%                             files = fieldnames(data.res.(analysis{j}));
%                             for k = 1:length(files)
%                                 objs = fieldnames(data.res.(analysis{j}).(files{k}));
%                                 for ii = 1:length(objs)
%                                     sigs = fieldnames(data.res.(analysis{j}).(files{k}).(objs{ii}));
%                                     for jj = 1:length(sigs)
%                                         results = fieldnames(data.res.(analysis{j}).(files{k}).(objs{ii}).(sigs{jj}));
%                                         for kk = 1:length(results)
%                                             dataExport.analysis{ind, 1} = analysis{j};
%                                             dataExport.file{ind, 1} = files{k};
%                                             dataExport.object{ind, 1} = objs{ii};
%                                             dataExport.signal{ind, 1} = sigs{jj};
%                                             dataExport.(results{kk}){ind, 1} = mean(data.res.(analysis{j}).(files{k}).(objs{ii}).(sigs{jj}).(results{kk}), 1, 'omitnan');
%                                         end
%                                         ind = ind + 1;
%                                     end
%                                 end
%                             end
%                         end

                end
            end

            app.UITableExport.Data = struct2table(dataExport);
            app.UITableExport.ColumnName = fieldnames(dataExport);

        end

        function config = loadConfig(app)

            % Find the path to the main folder.
            m_analysisPath = mfilename('fullpath');
            ind = strfind(m_analysisPath,'\');
            m_analysisPath(ind(end):end) = [];

            % Create the config file if it doesn't exist.
            if ~isfile([m_analysisPath '\config.xml'])
                createConfig([m_analysisPath '\']);
            end

            % Load the config file.
            config = readstruct([m_analysisPath '\config.xml']);
            
        end
        
        
        function saveConfig(app, config)
            % Find the path to the main folder.
            m_analysisPath = mfilename('fullpath');
            ind = strfind(m_analysisPath,'\');
            m_analysisPath(ind(end):end) = [];
            
            % Load the config file.
            writestruct(config, [m_analysisPath '\config.xml']);
        end
        
        function data = loadData(app)

            printLog(app, '004', [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value])

            files = app.TableFiles.Data;
            if isempty(files)
                return
            end

            filesLoad = dir(app.FilesLoad.Value);
            ind3 = find(contains({filesLoad.name}', app.FileExtensionDropDown.Value) & contains({filesLoad.name}', app.EquipmentDropDown.Value) & ~contains({filesLoad.name}', 'asv'));

            if strcmp(filesLoad(ind3).name(1:end - 2), 'load_mat_BAR')
                load([files.folder{1} '\' files.name{1}])
            else
                data = struct;
                ind1 = 1;

                for i = 1:height(files)

                    % The name of the file will be used as the parent data
                    % field. It cannot have certain characters as a structure
                    % field. These are all replaced with underscores.
                    if i < 10
                        name = ['files000' num2str(i)];
                    elseif i < 100
                        name = ['files00' num2str(i)];
                    elseif i < 1000
                        name = ['files0' num2str(i)];
                    elseif i < 10000
                        name = ['files' num2str(i)];
                    else
                        name = ['files' num2str(i)];
                    end

                    try
                        data.raw.(name) = feval(filesLoad(ind3).name(1:end - 2), [files.folder{i} '\' files.name{i}]);
                        data.raw.(name).groups = groupAuto(app, 'filename', [files.folder{i}, '\', files.name{i}]);
                        data = groupAuto(app, 'object', data);
                    catch
                        printLog(app, '003', [files.folder{i} '\' files.name{i}])
                    end

                end
            end
            
            printLog(app, '005', [app.FileExtensionDropDown.Value app.EquipmentDropDown.Value])

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % The goal of the current version of the configuration file is
            % to quicken the app startup processes. There are methods to
            % programatically configure these but they are only run as
            % needed.
            
            % Load the config file.
            config = loadConfig(app);

            % Set the directory and database text fields using the saved
            % configuration.
            app.Directory.Value = config.directory;
            app.Database.Value = config.database;

            % Set the file extension options using the saved configuration.
            app.FileExtensionDropDown.Items = fieldnames(config.options.filetypes);
            % Run the callback for the file extension selection object in
            % the app so the format selection object displays the correct
            % options.
            app.FileExtensionDropDownValueChanged;
            
            % Set the directories in the configuration tab using what is 
            % saved in the app.
            app.FilesLoad.Value = config.options.filesload;
            app.FilesProcess.Value = config.options.filesprocess;
            app.FilesAnalysis.Value = config.options.filesanalysis;

            % Set the analysis method options.
            app.AnalysisDropDown.Items = config.options.analysis;

            % Set the processing method options.
            app.SelectProcessingModuleDropDown.Items = config.options.process;
            
            % Set the review method options.
            app.ReviewDropDown.Items = config.review(1:2:end);

            % Set the export format options.
            app.FormatsDropDown.Items = config.export;
            
        end

        % Button pushed function: AddDirectorytoSearchButton
        function AddDirectorytoSearchButtonPushed(app, event)
            % Allow the user to select the parent directory.
            m_path = uigetdir(pwd,'Please select the data directory');
            % The directory is saved to the text field where it will be
            % pulled from later.
            if m_path == 0
                return
            end
            if isempty(app.Directory.Value)
                app.Directory.Value = m_path;
            else
                text = app.Directory.Value;
                app.Directory.Value{length(app.Directory.Value) + 1, 1} = m_path;
            end

            config = loadConfig(app);
            config.directory = app.Directory.Value;
            saveConfig(app, config)

        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            
            % Pull the selected directory from the app text box.
            m_directory = app.Directory.Value;
            % Pull the selected file extension from the app dropdown.
            m_fileType = app.FileExtensionDropDown.Value;
            % Pull the equipment type from the app dropdown.
            m_equipmentType = app.EquipmentDropDown.Value;
            % Pull the search tags the user entered into the app text box.
            m_searchTags = app.SearchTagsInclude.Value;
            
            % Initialize an empty structure for the object to store the
            % file information.
            files = struct;
            % Perform recursive file search.
            printLog(app, '001', {''})
            for i = 1:length(m_directory)
                files = filesearch(app, files, m_directory{i});
            end
            if isempty(fieldnames(files))
                printLog(app, '016')
                return
            end

            printLog(app, '002', {length(files.name);sum(files.bytes)})
            % Put the files found into the app table.
            app.TableFiles.Data = struct2table(files);
%             app.TableFiles.ColumnName = fieldnames(files);

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            data = loadData(app);

            setappdata(app.TableFiles,'data',data)

            if isfield(data, 'res')
                app.AnalysesAvailableforExportListBox.Items = fieldnames(data.res);
            end

            app.IdentifyGroupsButton.Enable = 1;
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            
            data = getappdata(app.TableFiles, 'data');
            
            analysis = app.AnalysisDropDown.Value;
            
            config = loadConfig(app);
            ind = find(strcmp(config.analysis, analysis));

            printLog(app ,'022', analysis)
            
            app.AppAnalysis = feval(['analysis_' analysis], app, data, analysis);

            printLog(app ,'023', analysis)
            
        end

        % Value changed function: FileExtensionDropDown
        function FileExtensionDropDownValueChanged(app, event)
            % Get the currently selected file extension.
            value = app.FileExtensionDropDown.Value;
            % Load the configuration file with the matching file extensions
            % and equipment names.
            config = loadConfig(app);
            % Get the file extension options.
            options = fieldnames(config.options.filetypes);
            for i = 1:length(options)
                if strcmp(value, options{i})
                    app.EquipmentDropDown.Items = config.options.filetypes.(options{i});
                    break
                end
            end
            
        end

        % Button pushed function: ButtonFormatExport
        function ButtonFormatExportPushed(app, event)

            data = getappdata(app.TableFiles, 'data');

            switch app.FormatsDropDown.Value
                case 'General'
                    dataExport = formatResults(app, data);
                    app.UITableExport.Data = struct2table(dataExport);
                    app.UITableExport.ColumnName = fieldnames(dataExport);
                    setappdata(app.UITableExport,'exportType','General')
                    printLog(app, '008')
                case 'BU Caltesting'
                    dataExport = formatBUCaltest(app, data);
                    setappdata(app.UITableExport,'dataExport',dataExport)
                    setappdata(app.UITableExport,'exportType','BU Caltesting')
                    printLog(app, '009')
            end

        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            
            exportType = app.FormatsDropDown.Value;
            
            m_resultsPath = app.Database.Value;
            
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            switch exportType
                case 'General'
                    dataExport = app.UITableExport.Data;
                    [file, path] = uiputfile('.xlsx', 'Save As', [m_resultsPath '\Export\General' timeStamp '.xlsx']);
                    if isnumeric(file) && file == 0
                        return
                    else
                        writetable(dataExport,[path file])
                    end
                case 'BU Caltesting'
                    dataExport = getappdata(app.UITableExport,'dataExport');
                    fields = fieldnames(dataExport);
                    fileName = [m_resultsPath '\Export\' exportType timeStamp '.xlsx'];
                    for i = 1:length(fields)
                        xlswrite(fileName,dataExport.(fields{i}),fields{i})
                    end
            end

            printLog(app, '007')
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            data = getappdata(app.TableFiles, 'data');
            
            process = app.SelectProcessingModuleDropDown.Value;
            
            app.AppAnalysis = feval(['process_' process], app, data);
        end

        % Button pushed function: SaveCurrentDataButton
        function SaveCurrentDataButtonPushed(app, event)
            
            data = getappdata(app.TableFiles, 'data');

            m_resultsPath = app.Database.Value;

            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            [file, path] = uiputfile('.mat', 'Save As', [m_resultsPath '\Data\' app.FileExtensionDropDown.Value app.EquipmentDropDown.Value timeStamp '.mat']);
            if isnumeric(file) && file == 0
                return
            else
                printLog(app, '012')
                save([path file], 'data', '-v7.3')

                printLog(app, '013')
            end

        end

        % Button pushed function: SelectWorkingDatabaseButton
        function SelectWorkingDatabaseButtonPushed(app, event)
            % Allow the user to select the working directory to store data.
            m_path = uigetdir(pwd,'Please select the working directory');
            % The directory is saved to the text field where it will be
            % pulled from later.
            if m_path==0
                return
            end
            app.Database.Value = m_path;

            createDatabase(app);


        end

        % Button pushed function: ReviewAnalysisButton
        function ReviewAnalysisButtonPushed(app, event)
            data = getappdata(app.TableFiles, 'data');
            
            review = app.ReviewDropDown.Value;
            
            config = loadConfig(app);
            ind = find(strcmp(config.review, review));
            
            app.AppAnalysis = feval(config.review{ind + 1}, app);
        end

        % Button pushed function: IdentifyGroupsButton
        function IdentifyGroupsButtonPushed(app, event)
            
            data = getappdata(app.TableFiles, 'data');
            app.AppAnalysis = process_Groupings(app, data);

        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            
            printLog(app, '014')

            files = app.TableFiles.Data;
            if isempty(files)
                return
            end

            targetDirectory = uigetdir(app.Database.Value, 'Please select a target directory to move the files to');

            filesLoad = dir(app.FilesLoad.Value);
            ind3 = find(contains({filesLoad.name}', app.FileExtensionDropDown.Value) & contains({filesLoad.name}', app.EquipmentDropDown.Value) & ~contains({filesLoad.name}', 'asv'));

            for i = 1:height(files)

                [status, msg] = movefile([files.folder{i} '\' files.name{i}], targetDirectory);
                if status ~= 0
                    printLog(app, '017', msg)
                end

            end
            
            printLog(app, '015')
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
            printLog(app, '018')

            files = app.TableFiles.Data;
            if isempty(files)
                return
            end

            targetDirectory = uigetdir(app.Database.Value, 'Please select a target directory to move the files to');

            filesLoad = dir(app.FilesLoad.Value);
            ind3 = find(contains({filesLoad.name}', app.FileExtensionDropDown.Value) & contains({filesLoad.name}', app.EquipmentDropDown.Value) & ~contains({filesLoad.name}', 'asv'));

            for i = 1:height(files)

                [status, msg] = copyfile([files.folder{i} '\' files.name{i}], targetDirectory);
                if status ~= 1
                    printLog(app, '020', msg)
                end

            end
            
            printLog(app, '019')
        end

        % Button pushed function: UpdateConfigurationFileButton
        function UpdateConfigurationFileButtonPushed(app, event)
            identifyFileTypes(app)
            identifyProcessOptions(app)
            identifyAnalysisOptions(app)
        end

        % Button pushed function: FindeligibleexportsButton
        function FindeligibleexportsButtonPushed(app, event)
            data = getappdata(app.TableFiles, 'data');
            app.SelectitemstoexportListBox.Items = fieldnames(data);

        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            
            process_Merge(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseSearchTab = uitab(app.TabGroup);
            app.DatabaseSearchTab.Title = 'Database Search';
            app.AddDirectorytoSearchButton = uibutton(app.DatabaseSearchTab, 'push');
            app.AddDirectorytoSearchButton.ButtonPushedFcn = createCallbackFcn(app, @AddDirectorytoSearchButtonPushed, true);
            app.AddDirectorytoSearchButton.Text = 'Add Directory to Search';
            app.SelectfileextensionofthedatafilesLabel = uilabel(app.DatabaseSearchTab);
            app.SelectfileextensionofthedatafilesLabel.Text = {'Select file extension'; 'of the data files.'};
            app.FileExtensionDropDown = uidropdown(app.DatabaseSearchTab);
            app.FileExtensionDropDown.Items = {};
            app.FileExtensionDropDown.ValueChangedFcn = createCallbackFcn(app, @FileExtensionDropDownValueChanged, true);
            app.FileExtensionDropDown.Value = {};
            app.SelectequipmentthedatawasrecordedfromLabel = uilabel(app.DatabaseSearchTab);
            app.SelectequipmentthedatawasrecordedfromLabel.Text = {'Select equipment the'; 'data was recorded from.'};
            app.EquipmentDropDown = uidropdown(app.DatabaseSearchTab);
            app.EquipmentDropDown.Items = {};
            app.EquipmentDropDown.Value = {};
            app.EditFieldLabel = uilabel(app.DatabaseSearchTab);
            app.EditFieldLabel.Text = {'Enter specific strings separated by'; 'commas to filter the files selected.'};
            app.SearchTagsInclude = uieditfield(app.DatabaseSearchTab, 'text');
            app.TableFiles = uitable(app.DatabaseSearchTab);
            app.TableFiles.ColumnName = {'File Name'; 'Folder'; 'Size (MB)'};
            app.TableFiles.RowName = {};
            app.FindButton = uibutton(app.DatabaseSearchTab, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.LoadButton = uibutton(app.DatabaseSearchTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveCurrentDataButton = uibutton(app.DatabaseSearchTab, 'push');
            app.SaveCurrentDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentDataButtonPushed, true);
            app.SaveCurrentDataButton.Text = 'Save Current Data';
            app.SelectWorkingDatabaseButton = uibutton(app.DatabaseSearchTab, 'push');
            app.SelectWorkingDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWorkingDatabaseButtonPushed, true);
            app.SelectWorkingDatabaseButton.Text = 'Select Working Database';
            app.Database = uieditfield(app.DatabaseSearchTab, 'text');
            app.IdentifyGroupsButton = uibutton(app.DatabaseSearchTab, 'push');
            app.IdentifyGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyGroupsButtonPushed, true);
            app.IdentifyGroupsButton.Enable = 'off';
            app.IdentifyGroupsButton.Text = 'Identify Groups';
            app.SearchTagsExclude = uieditfield(app.DatabaseSearchTab, 'text');
            app.IncludeLabel = uilabel(app.DatabaseSearchTab);
            app.IncludeLabel.Text = 'Include';
            app.ExcludeLabel = uilabel(app.DatabaseSearchTab);
            app.ExcludeLabel.Text = 'Exclude';
            app.MoveButton = uibutton(app.DatabaseSearchTab, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.CopyButton = uibutton(app.DatabaseSearchTab, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.MergeButton = uibutton(app.DatabaseSearchTab, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge';
            app.Directory = uitextarea(app.DatabaseSearchTab);
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.SelectProcessingModuleDropDownLabel = uilabel(app.ProcessingTab);
            app.SelectProcessingModuleDropDownLabel.Text = 'Select Processing Module';
            app.SelectProcessingModuleDropDown = uidropdown(app.ProcessingTab);
            app.SelectProcessingModuleDropDown.Items = {};
            app.SelectProcessingModuleDropDown.Value = {};
            app.ProcessButton = uibutton(app.ProcessingTab, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisDropDownLabel = uilabel(app.AnalysisTab);
            app.AnalysisDropDownLabel.Text = 'Analysis';
            app.AnalysisDropDown = uidropdown(app.AnalysisTab);
            app.AnalysisDropDown.Items = {};
            app.AnalysisDropDown.Value = {};
            app.RunAnalysisButton = uibutton(app.AnalysisTab, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            app.ReviewAnalysisButton = uibutton(app.ReviewTab, 'push');
            app.ReviewAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ReviewAnalysisButtonPushed, true);
            app.ReviewAnalysisButton.Text = 'Review Analysis';
            app.AnalysisDropDown_2Label = uilabel(app.ReviewTab);
            app.AnalysisDropDown_2Label.Text = 'Analysis';
            app.ReviewDropDown = uidropdown(app.ReviewTab);
            app.ReviewDropDown.Items = {};
            app.ReviewDropDown.Value = {};
            app.StatisticsTab = uitab(app.TabGroup);
            app.StatisticsTab.Title = 'Statistics';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ButtonFormatExport = uibutton(app.ExportTab, 'push');
            app.ButtonFormatExport.ButtonPushedFcn = createCallbackFcn(app, @ButtonFormatExportPushed, true);
            app.ButtonFormatExport.Text = 'Format loaded results for export';
            app.UITableExport = uitable(app.ExportTab);
            app.UITableExport.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableExport.RowName = {};
            app.FormatsDropDownLabel = uilabel(app.ExportTab);
            app.FormatsDropDownLabel.Text = 'Formats';
            app.FormatsDropDown = uidropdown(app.ExportTab);
            app.FormatsDropDown.Items = {};
            app.FormatsDropDown.Value = {};
            app.ExportResultsButton = uibutton(app.ExportTab, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.FindeligibleexportsButton = uibutton(app.ExportTab, 'push');
            app.FindeligibleexportsButton.ButtonPushedFcn = createCallbackFcn(app, @FindeligibleexportsButtonPushed, true);
            app.FindeligibleexportsButton.Text = 'Find eligible exports';
            app.SelectitemstoexportListBoxLabel = uilabel(app.ExportTab);
            app.SelectitemstoexportListBoxLabel.Text = 'Select items to export';
            app.SelectitemstoexportListBox = uilistbox(app.ExportTab);
            app.SelectitemstoexportListBox.Items = {};
            app.SelectitemstoexportListBox.Value = {};
            app.ExportMeansSwitchLabel = uilabel(app.ExportTab);
            app.ExportMeansSwitchLabel.Text = 'Export Means';
            app.ExportMeansSwitch = uiswitch(app.ExportTab, 'slider');
            app.ExportMeansSwitch.Items = {'No', 'Yes'};
            app.ExportMeansSwitch.Value = 'No';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.LoadingscriptsLabel = uilabel(app.ConfigurationTab);
            app.LoadingscriptsLabel.Text = 'Load';
            app.FilesLoad = uieditfield(app.ConfigurationTab, 'text');
            app.UpdateConfigurationFileButton = uibutton(app.ConfigurationTab, 'push');
            app.UpdateConfigurationFileButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateConfigurationFileButtonPushed, true);
            app.UpdateConfigurationFileButton.Text = 'Update Configuration File';
            app.MethodDirectoriesLabel = uilabel(app.ConfigurationTab);
            app.MethodDirectoriesLabel.Text = 'Method Directories';
            app.ProcessLabel = uilabel(app.ConfigurationTab);
            app.ProcessLabel.Text = 'Process';
            app.FilesProcess = uieditfield(app.ConfigurationTab, 'text');
            app.ProcessLabel_2 = uilabel(app.ConfigurationTab);
            app.ProcessLabel_2.Text = 'Analysis';
            app.FilesAnalysis = uieditfield(app.ConfigurationTab, 'text');
            app.Log = uitextarea(app.UIFigure);
            app.BiomechanicsAnalysisandReportingApplicationLabel = uilabel(app.UIFigure);
            app.BiomechanicsAnalysisandReportingApplicationLabel.Text = 'Biomechanics Analysis and Reporting Application';
            app.v10Label = uilabel(app.UIFigure);
            app.v10Label.Text = 'v1.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BAR_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end