classdef process_Groupings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MovetagstogroupsButton        matlab.ui.control.Button
        EntertagstoremovefromsignalsEditField  matlab.ui.control.EditField
        EntertagstoremovefromsignalsEditFieldLabel  matlab.ui.control.Label
        RemovegroupsatanindexButton   matlab.ui.control.Button
        RemoveallgroupsatanindexSpinner  matlab.ui.control.Spinner
        SelectthegroupwithdatestoconvertSpinner  matlab.ui.control.Spinner
        SelectthegroupwithdatestoconvertSpinnerLabel  matlab.ui.control.Label
        SelectthegroupdatesbelongtoSpinner  matlab.ui.control.Spinner
        SelectthegroupdatesbelongtoSpinnerLabel  matlab.ui.control.Label
        RemoveDuplicateswithinColumnsButton  matlab.ui.control.Button
        RemoveDuplicateswithinRowsButton  matlab.ui.control.Button
        ConvertObjectstoGroupsButton  matlab.ui.control.Button
        Version                       matlab.ui.control.Label
        SaveResultsandExitButton      matlab.ui.control.Button
        ConvertDatesButton            matlab.ui.control.Button
        SelectdatetagformatDropDown   matlab.ui.control.DropDown
        SelectdatetagformatDropDownLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Image                         matlab.ui.control.Image
        GroupingsLabel                matlab.ui.control.Label
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of
            %           the error and organize the errors available.
            %         - newText, a string that may be inserted into the
            %                    message.
            % Remarks
            % - This method will print messages to the log of the BAR App.
            %   The error codes are unique to each message so they can be
            %   found and referenced easier.
            % - The method is public and is acessible outside of the BAR
            %   App. It is used in some of the example analysis and process
            %   scripts.
            % Future Work
            % - This log should be printed and saved to a file location
            %   each time the BAR App is run.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the current text within the log.
            text = app.BAR_App.Log.Value;
            % Match the error code with the message and update the text for
            % the log.
            switch code
                case '001'
                    text{length(text) + 1,1} = ['GRP-001 removing duplicates within columns'];
                case '002'
                    text{length(text) + 1,1} = ['GRP-002 duplicates removed from within columns'];
                case '003'
                    text{length(text) + 1,1} = ['GRP-003 removing duplicates within rows'];
                case '004'
                    text{length(text) + 1,1} = ['GRP-004 duplicates removed from within rows'];
                case '005'
                    text{length(text) + 1,1} = ['GRP-005 identifying object groups'];
                case '006'
                    text{length(text) + 1,1} = ['GRP-006 identifying object groups complete'];
                case '007'
                    text{length(text) + 1,1} = ['GRP-007 updating table'];
                case '008'
                    text{length(text) + 1,1} = ['GRP-008 updating table complete'];
                case '009'
                    text{length(text) + 1,1} = ['GRP-009 starting to remove tags'];
                case '010'
                    text{length(text) + 1,1} = ['GRP-010 ' newText];
            end
            % Update the log.
            app.BAR_App.Log.Value = text;
            % Scroll down so the message is visible.
            scroll(app.BAR_App.Log,'bottom')
            drawnow
        end
        
        
        function updateTable(app, data)
            % updateTable(app, data)
            % inputs  - app, required mlapp object.
            %         - data, BAR App data structure
            % Remarks
            % - This method will update the table in the app with the current group information.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Print a message to the log that it is starting.
            printLog(app, '007')
            
            % Initialize the structure.
            groups = struct;

            % Get the file names so they can be iterated through.
            files = fieldnames(data.raw);
            % We'll use a separate index so the group and file information can be combined.
            ind = 1;
            for i = 1:length(files)

                % Get the object names and remove the informational ones.
                obj = fieldnames(data.raw.(files{i}));
                obj(strcmp(obj, 'meta')) = [];
                obj(strcmp(obj, 'groups')) = [];

                % If there are no objects only the file groups are needed.
                if isempty(obj)
                    % Initialize a count for the groups.
                    g = 1;
                    % The first group is the programmed file name.
                    groups.(['GroupFile' num2str(g)]){ind, 1} = files{i};
                    g = g + 1;
                    % Add all the group information for the file as additional groups.
                    for j = 1:length(data.raw.(files{i}).groups)
                        groups.(['GroupFile' num2str(g)]){ind, 1} = data.raw.(files{i}).groups{j};
                        g = g + 1;
                    end
                    ind = ind + 1;
                end
                % If there are objects add them.
                for k = 1:length(obj)
                    g = 1;
                    % The first group is the programmed file name.
                    groups.(['GroupFile' num2str(g)]){ind, 1} = files{i};
                    g = g + 1;
                    % Add all the group information for the file as additional groups.
                    for j = 1:length(data.raw.(files{i}).groups)
                        groups.(['GroupFile' num2str(g)]){ind, 1} = data.raw.(files{i}).groups{j};
                        g = g + 1;
                    end
                    % Add the object name.
                    groups.(['GroupObject' num2str(g)]){ind, 1} = obj{k};
                    g = g + 1;
                    % Check if there is group information for the object.
                    if isfield(data.raw.(files{i}).(obj{k}), 'groups')
                        % Iterate through the group informatioin and add them to the structure.
                        for ii = 1:length(data.raw.(files{i}).(obj{k}).groups)
                            groups.(['GroupObject' num2str(g)]){ind, 1} = data.raw.(files{i}).(obj{k}).groups{ii};
                            g = g + 1;
                        end
                    end
                    ind = ind + 1;
                end
            end

            % To display the structure in the table the different fields need the same number of elements.
            N = [];
            groupNames = fieldnames(groups);
            % Find the length of all the groups.
            for i = 1:length(groupNames)
                N(i) = length(groups.(groupNames{i}));
            end
            % Pad the shorter groups so they are the same length as the longest.
            for i = 1:length(groupNames)
                if length(groups.(groupNames{i})) < max(N)
                    groups.(groupNames{i})(N(i) + 1:max(N), 1) = {'NA'};
                end
            end
            
            % Set the group structure as the table.
            app.UITable.Data = struct2table(groups);
            % Use the fieldnames as the column headers.
            app.UITable.ColumnName = fieldnames(groups);

            % Print a message that the update as finished.
            printLog(app, '008')

        end
    end
    
    methods (Access = public)
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % StartupFcn(app, mainapp, data)
            % inputs  - app, required mlapp object.
            %         - mainapp, BAR App that will be used to return data to it.
            %         - data, BAR App data structure
            % Remarks
            % - This is the required startup app. It is used to bring the data into the app and initialize the table with group information.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            % Store the data in the app.
            setappdata(app.UITable, 'data', data)
            % Update the table.
            updateTable(app, data)
            % Update the version number label with the label from the BAR App.
            app.Version.Text = app.BAR_App.Version.Text;
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitSaveResultsandExitButtonPushed(app, event)
            % SaveResultsandExitSaveResultsandExitButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method will save the data into the BAR App.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the data.
            data = getappdata(app.UITable, 'data');
            % Get the name of the analysis.
            analysis = app.GroupingsLabel.Text;
            
            % Run the public BAR App analysisComplete method to get the data back into the app.
            analysisComplete(app.BAR_App, data, analysis, 0)

            % Delete this app.
            delete(app)
        end

        % Callback function
        function findDelimiter(app, event)
            % findDelimiter(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method was used previously but is not currently. It was previously part of the group processing to create the group information
            %   from the object names. Potetially it could be used later to do additional group processing with varied delimiters.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            data = getappdata(app.UITable, 'data');

            n = [];
            files = fieldnames(data.raw);
            for i = 1:length(files)
                objs = fieldnames(data.raw.(files{i}));
                groups = split(objs, '_');
                j = 1;
                while j <= size(groups, 2)
                    if numel(unique(groups(:,j))) == length(groups) || numel(unique(groups(:,j))) == 1
                        groups(:,j) = [];
                    else
                        n = [n;numel(unique(groups(:,j)))];
                        j = j + 1;
                    end
                end
                for j = 1:size(groups, 2)
                    groupsTable.(['g' num2str(j)]) = [unique(groups(:,j)); num2cell(nan(max(n) - n(j), 1))];
                end
                for j = 1:length(objs)
                    for k = 1:size(groups, 2)
                        data.raw.(files{i}).(objs{j}).groups.(['g' num2str(k)]) = groups{j,k};
                    end
                end
            end
            setappdata(app.UITable, 'data', data)
            app.UITable.Data = struct2table(groupsTable);
            app.UITable.ColumnName = fieldnames(groupsTable);

        end

        % Button pushed function: ConvertDatesButton
        function ConvertDatesButtonPushed(app, event)
            % ConvertDatesButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method is used to convert dates in the groups that are stored as strings into ordinal numbers. This does require input from the
            %   user to select the format of the date string, the groups with the date information and the group they belong to.
            % Future Work
            % - Potentialy the group with the dates could be automatically found. Input from the user does make it easier.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the groups from the table. This was used by the user to select the date groups.
            groups = app.UITable.Data;
            % Get the data from the app.
            data = getappdata(app.UITable, 'data');

            % Get the user selected format of the date string.
            dateFormat = app.SelectdatetagformatDropDown.Value;
            % Get the index of the group the dates belong to.
            ind_parent = app.SelectthegroupdatesbelongtoSpinner.Value;
            % Get the index of the date group.
            ind_date = app.SelectthegroupwithdatestoconvertSpinner.Value;

            % Get all the unqiue values of the parent group so the dates can be numbered sequentially.
            parent = table2cell(unique(groups(:, ind_parent)));
            % Get the file names so they can be iterated through.
            files = fieldnames(data.raw);
            % Start iterating through the unique parent names.
            for i = 1:length(parent)
                % Get the unique dates for
                dates = datetime(unique(table2cell(groups(find(strcmp(groups.(ind_parent), parent{i})), ind_date))), 'InputFormat', dateFormat);
                % Sort the dates.
                dates = sort(dates);
                % Iterate through the files and add an ordinal number to the file group information.
                for j = 1:length(files)
                    % Check that the current parent is a group in the current file.
                    if any(strcmp(data.raw.(files{j}).groups, parent{i}))
                        % Get the ordinal number of the date.
                        n = num2str(find(dates==datetime(data.raw.(files{j}).groups{ind_date - 1}, 'InputFormat', dateFormat)));
                        % Add the ordinal number to the group information.
                        data.raw.(files{j}).groups = [data.raw.(files{j}).groups(1:ind_date - 1); n; data.raw.(files{j}).groups(ind_date - 1 + 1:end)];
                    end
                end
            end

            % Set the data back into the app.
            setappdata(app.UITable, 'data', data)

            % Update the table with the new date information.
            updateTable(app, data)

        end

        % Button pushed function: ConvertObjectstoGroupsButton
        function ConvertObjectstoGroupsButtonPushed(app, event)
            % ConvertObjectstoGroupsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method was used previously to identify group information from objects. It is not currently used.
            % Future Work
            % - The method could be implemented again to identify groups from more variable deliminations or other user specified methods.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            printLog(app, '005')

            data = getappdata(app.UITable, 'data');

            files = fieldnames(data.raw);
            for i = 1:length(files)
                obj = fieldnames(data.raw.(files{i}));
                obj(strcmp(obj, 'meta')) = [];
                obj(strcmp(obj, 'groups')) = [];
                for j = 1:length(obj)
                    data.raw.(files{i}).(obj{j}).groups = split(obj{j}, '_');
                end
            end

            setappdata(app.UITable, 'data', data)

            updateTable(app, data)

            printLog(app, '006')

        end

        % Button pushed function: RemoveDuplicateswithinRowsButton
        function RemoveDuplicateswithinRowsButtonPushed(app, event)
            % RemoveDuplicateswithinRowsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method will remove duplicate group information within each file. The duplication does need to be consistent across files or they
            %   will not be removed. However the values themselves do not need to be consistent across files. One instance of the duplicate
            %   information will not be removed.
            % Future Work
            % - The method may not handle object group informaitoin very well. Good test data will be needed for that.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Print a message to the log that the method is starting.
            printLog(app, '003')

            % The data in the table will be used to find the duplicates.
            groups = app.UITable.Data;
            % Pull the current data from the app.
            data = getappdata(app.UITable, 'data');

            % Identify the duplicates within the rows.
            dups = [];
            for i = 1:height(groups)
                % Convert the current table row into cells.
                groupsUnique = table2cell(groups(i,:));
                % Remove empty cells. Empty cells will cause errors.
                groupsUnique(cellfun(@isempty, groupsUnique)) = [];
                % Find the unique groups.
                [~, idxu, idxc] = unique(groupsUnique);
                % Find the index of the unique values that have a count
                % greater than 1.
                ind_file = sort(idxu(find(histcounts(idxc, 0:max(idxc) + 1) > 1) - 1));
                % Save the indexes of the duplicates.
                dups(i,1:length(ind_file)) = ind_file;
            end
            
            % Check that the duplicates are common to every row.
            i = 1;
            while i <= size(dups, 2)
                % If there is more than one unique value in the column than
                % the rows have different values.
                if numel(unique(dups(:,i))) ~= 1
                    % Remove that column as a duplicate.
                    dups(:,i) = [];
                else
                    i = i + 1;
                end
            end

            % The fieldnames of the groups will be needed to remove them.
            fields = fieldnames(groups);
            % Iterate horizontally through each of the duplications.
            for i = 1:size(dups, 2)
                % Iterate vertically through the groups.
                for j = 1:height(groups)

                    % Get the string that is duplicated.
                    dup = groups.(fields{dups(j,i)})(j);
                    % Find the index of the exact string that is duplicated.
                    ind_file = find(strcmp(data.raw.(groups.GroupFile1{j}).groups, dup));
                    % Remove it if it was found.
                    if ~isempty(ind_file)
                        data.raw.(groups.GroupFile1{j}).groups(ind_file(2:end)) = [];
                    end

                end
            end
            
            % Save the data back into the app.
            setappdata(app.UITable, 'data', data)

            % Print a meassage that the method has completed.
            printLog(app, '004')

            % Update the table with the new information.
            updateTable(app, data)
            
        end

        % Button pushed function: RemoveDuplicateswithinColumnsButton
        function RemoveDuplicateswithinColumnsButtonPushed(app, event)
            % RemoveDuplicateswithinColumnsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method will remove duplicate group information across files. This information would be the same string in the same group
            %   locatiion across all files.
            % Future Work
            % - The method may not handle object group informaitoin very well. Good test data will be needed for that.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Print a message that the method is starting.
            printLog(app, '001')

            % Get the group information from the table.
            groups = app.UITable.Data;
            % Get the current data.
            data = getappdata(app.UITable, 'data');
            % Get the column headers for the groups.
            cols = fieldnames(groups);
            
            % The file names will be needed for the removal.
            files = fieldnames(data.raw);
            % Start iterating through the columns backwards so the indexes are preserved.
            for i = length(cols) - 3 : -1 : 2
                % if none of the groups are empty and there is only 1 unique string, or all the items are empty.
                if (sum(cellfun(@isempty, groups.(cols{i}))) == 0 && numel(unique(groups.(cols{i}))) == 1) || sum(cellfun(@isempty, groups.(cols{i}))) == height(groups.(cols{i}))
                    % Check that the column is a file group.
                    if contains(cols{i}, 'File')
                        % Remove the index of the group for all data.
                        for j = 1:length(files)
                            if i - 1 <= length(data.raw.(files{j}).groups)
                                data.raw.(files{j}).groups(i - 1) = [];
                            end
                        end
                    % Check if the column is from the objects.
                    elseif contains(cols{i}, 'Object')
                        % Iterate through the files.
                        for j = 1:length(files)
                            % Get the object names.
                            objs = fieldnames(data.raw.(files{j}));
                            % Remove the informational ones.
                            objs(strcmp(objs, 'meta')) = [];
                            objs(strcmp(objs, 'groups')) = [];
                            % Iterate through the objects.
                            for k = 1:length(objs)
                                if i - length(data.raw.(files{j}).groups) - 2 <= length(data.raw.(files{j}).(objs{k}).groups)
                                    data.raw.(files{j}).(objs{k}).groups(i - length(data.raw.(files{j}).groups) - 2) = [];
                                end
                            end
                        end
                    end
                end
            end

            % Save the data back into the app.
            setappdata(app.UITable, 'data', data)

            % Print a meassage that the method has completed.
            printLog(app, '002')
            
            % Update the table with the new information.
            updateTable(app, data)
            
        end

        % Button pushed function: RemovegroupsatanindexButton
        function RemovegroupsatanindexButtonPushed(app, event)
            % RemovegroupsatanindexButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method will remove groups from a selected index regardless of their content. This was originally made to remove processed date
            %   groups.
            % Future Work
            % - None.
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the group information from the table.
            groups = app.UITable.Data;
            % Get the current data.
            data = getappdata(app.UITable, 'data');
            % Get the column headers for the groups.
            cols = fieldnames(groups);
            
            % The file names will be needed for the removal.
            files = fieldnames(data.raw);
            % Remove the index of the group for all data.
            for j = 1:length(files)
                if app.RemoveallgroupsatanindexSpinner.Value - 1 <= length(data.raw.(files{j}).groups)
                    data.raw.(files{j}).groups(app.RemoveallgroupsatanindexSpinner.Value - 1) = [];
                end
            end

            % Save the data back into the app.
            setappdata(app.UITable, 'data', data)

            % Update the table with the new information.
            updateTable(app, data)
        end

        % Button pushed function: MovetagstogroupsButton
        function MovetagstogroupsButtonPushed(app, event)
            % MovetagstogroupsButtonPushed(app, event)
            % inputs  - app, required mlapp object.
            %         - event, app information from when the button was pressed.
            % Remarks
            % - This method will remove tags from signals and move them to the group information. In the process it creates a new object using the tag
            %   if it has not already been created.
            % Future Work
            % - None.
            % Jan 2022 - Created by Ben Senderling, bsender@bu.edu

            % Print a message the process is starting.
            printLog(app, '009')
            
            % Get the current data.
            data = getappdata(app.UITable, 'data');

            % Pull the inclusion and exclusion tags entered into the app.
            m_tags_include = app.EntertagstoremovefromsignalsEditField.Value;
            % Read the strings with the tags and separate them into cell arrays.
            if ~isempty(m_tags_include)
                m_tags_include = textscan(m_tags_include,'%s','Delimiter',',');
            else
                return
            end

            % The order the tags are removed in matters so they need to be iterated through outside the other for loops. Signal names may have
            % different tags in different orders. So otherwise many more objects might be created.
            for ii = 1:length(m_tags_include{1})
                % Set a current tag.
                tagCurrent = m_tags_include{1}{ii};
                % Get the file names.
                files = fieldnames(data.raw);
                % Iterate through the file names.
                for i = 1:length(files)
                    % Get the object names.
                    objs = fieldnames(data.raw.(files{i}));
                    % Remove the informational ones.
                    objs(strcmp(objs, 'meta')) = [];
                    objs(strcmp(objs, 'groups')) = [];
                    % Iterate through the object names.
                    for j = 1:length(objs)
                        % Get the signal names.
                        sigs = fieldnames(data.raw.(files{i}).(objs{j}).data);
                        % Iterate through the signals.
                        for k = 1:length(sigs)
                            % If any tags were found in the signal name.
                            if contains(sigs{k}, tagCurrent)
                                % If both the first and last characters of the tag is an underscore.
                                % If the first character is an underscore and the last character is not then the tag needs to be at the beginning of 
                                % the signal.
                                % If the first character is not an underscore and the last character is then the tag needs to be at the end of the 
                                % signal.
                                % If neither the first or last character is an underscore.
                                if strcmp(tagCurrent(1), '_') && strcmp(tagCurrent(end), '_') || ...
                                        (~strcmp(tagCurrent(1), '_') && strcmp(tagCurrent(end), '_') && strcmp(sigs{k}(1:length(tagCurrent)), tagCurrent)) || ...
                                        (strcmp(tagCurrent(1), '_') && ~strcmp(tagCurrent(end), '_') && strcmp(sigs{k}(end - length(tagCurrent) + 1), tagCurrent)) || ...
                                        ~strcmp(tagCurrent(1), '_') && ~strcmp(tagCurrent(end), '_')
                                    % If the object-tag has not previously been created and the object has group information.
                                    if ~isfield(data.raw.(files{i}), [objs{j} '_' tagCurrent]) && isfield(data.raw.(files{i}).(objs{j}), 'groups')
                                        % Copy the group information to the new object.
                                        data.raw.(files{i}).([objs{j} '_' tagCurrent]).groups = [data.raw.(files{i}).(objs{j}).groups; {tagCurrent}];
                                    end
                                    % Remove the tag from the signal name.
                                    sigNew = regexprep(sigs{k}, tagCurrent, '');
                                    % Remove any repeated underscores if they were created.
                                    sigNew = regexprep(sigNew, '__', '_');
                                    % Remove an underscore in the first value if it was created.
                                    if strcmp(sigNew(1), '_')
                                        sigNew(1) = [];
                                    end
                                    % Copy the signal to the new object.
                                    data.raw.(files{i}).([objs{j} '_' tagCurrent]).data.(sigNew) = data.raw.(files{i}).(objs{j}).data.(sigs{k});
                                    % Remove the signal from the old object.
                                    data.raw.(files{i}).(objs{j}).data = rmfield(data.raw.(files{i}).(objs{j}).data, sigs{k});
                                end
                            end
                        end
                    end
                end
                % Print an update message to the log.
                printLog(app, '010', ['tag ' num2str(ii) ' of ' num2str(length(m_tags_include{1})) ' removed'])
            end

            % Save the data back into the app.
            setappdata(app.UITable, 'data', data)

            % Update the table with the new information.
            updateTable(app, data)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.GroupingsLabel = uilabel(app.UIFigure);
            app.GroupingsLabel.Text = 'Groupings';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SelectdatetagformatDropDownLabel = uilabel(app.UIFigure);
            app.SelectdatetagformatDropDownLabel.Text = 'Select date tag format';
            app.SelectdatetagformatDropDown = uidropdown(app.UIFigure);
            app.SelectdatetagformatDropDown.Items = {'yyyyMMdd', 'MMddyy'};
            app.SelectdatetagformatDropDown.Editable = 'on';
            app.SelectdatetagformatDropDown.Value = 'yyyyMMdd';
            app.ConvertDatesButton = uibutton(app.UIFigure, 'push');
            app.ConvertDatesButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertDatesButtonPushed, true);
            app.ConvertDatesButton.Text = 'Convert Dates';
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitSaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.Version = uilabel(app.UIFigure);
            app.ConvertObjectstoGroupsButton = uibutton(app.UIFigure, 'push');
            app.ConvertObjectstoGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertObjectstoGroupsButtonPushed, true);
            app.ConvertObjectstoGroupsButton.Enable = 'off';
            app.ConvertObjectstoGroupsButton.Visible = 'off';
            app.ConvertObjectstoGroupsButton.Text = 'Convert Objects to Groups';
            app.RemoveDuplicateswithinRowsButton = uibutton(app.UIFigure, 'push');
            app.RemoveDuplicateswithinRowsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDuplicateswithinRowsButtonPushed, true);
            app.RemoveDuplicateswithinRowsButton.Text = 'Remove Duplicates within Rows';
            app.RemoveDuplicateswithinColumnsButton = uibutton(app.UIFigure, 'push');
            app.RemoveDuplicateswithinColumnsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDuplicateswithinColumnsButtonPushed, true);
            app.RemoveDuplicateswithinColumnsButton.Text = 'Remove Duplicates within Columns';
            app.SelectthegroupdatesbelongtoSpinnerLabel = uilabel(app.UIFigure);
            app.SelectthegroupdatesbelongtoSpinnerLabel.Text = 'Select the group dates belong to';
            app.SelectthegroupdatesbelongtoSpinner = uispinner(app.UIFigure);
            app.SelectthegroupdatesbelongtoSpinner.Value = 1;
            app.SelectthegroupwithdatestoconvertSpinnerLabel = uilabel(app.UIFigure);
            app.SelectthegroupwithdatestoconvertSpinnerLabel.Text = 'Select the group with dates to convert';
            app.SelectthegroupwithdatestoconvertSpinner = uispinner(app.UIFigure);
            app.SelectthegroupwithdatestoconvertSpinner.Value = 1;
            app.RemoveallgroupsatanindexSpinner = uispinner(app.UIFigure);
            app.RemovegroupsatanindexButton = uibutton(app.UIFigure, 'push');
            app.RemovegroupsatanindexButton.ButtonPushedFcn = createCallbackFcn(app, @RemovegroupsatanindexButtonPushed, true);
            app.RemovegroupsatanindexButton.Text = 'Remove groups at an index';
            app.EntertagstoremovefromsignalsEditFieldLabel = uilabel(app.UIFigure);
            app.EntertagstoremovefromsignalsEditFieldLabel.Text = 'Enter tags to remove from signals';
            app.EntertagstoremovefromsignalsEditField = uieditfield(app.UIFigure, 'text');
            app.MovetagstogroupsButton = uibutton(app.UIFigure, 'push');
            app.MovetagstogroupsButton.ButtonPushedFcn = createCallbackFcn(app, @MovetagstogroupsButtonPushed, true);
            app.MovetagstogroupsButton.Text = 'Move tags to groups';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Groupings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end