classdef process_Merge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ExportMatchingsButton     matlab.ui.control.Button
        Spinner                   matlab.ui.control.Spinner
        SpinnerLabel              matlab.ui.control.Label
        RunMatchButton            matlab.ui.control.Button
        Level2                    matlab.ui.control.DropDown
        SelectalevelfromtheseconddatasettomatchDropDownLabel  matlab.ui.control.Label
        Level1                    matlab.ui.control.DropDown
        SelectalevelfromthefirstdatasettomatchDropDownLabel  matlab.ui.control.Label
        SaveResultsandExitButton  matlab.ui.control.Button
        Log                       matlab.ui.control.TextArea
        UITable                   matlab.ui.control.Table
        Image                     matlab.ui.control.Image
        MergeLabel                matlab.ui.control.Label
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of
            %           the error and organize the errors available.
            %         - newText, a string that may be inserted into the
            %                    message.
            % Remarks
            % - This method will print messages to the log of the Merge 
            %   module. The error codes are unique to each message so they
            %   can be found and referenced easier.
            % Future Work
            % - This log should be printed and saved to a file location
            %   each time the BAR App is run.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text within the log.
            text = app.Log.Value;
            % Match the error code with the message and update the text for
            % the log.
            switch code
                case '001'
                    text{length(text) + 1, 1} = ['001, starting merge'];
                case '002'
                    text{length(text) + 1, 1} = ['002, merge complete'];
                case '003'
                    text{length(text) + 1, 1} = ['003, ' newText];
            end
            % Update the log.
            app.Log.Value = text;
            % Scroll to the bottom so the most recent message is visible.
            scroll(app.Log,'bottom')
            drawnow
        end
        
        
        function updateTable(app)
            % updateTable(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method will update the table in the module with the
            %   currently selected levels of the structure to perform the
            %   matching on. This is necessary to evaluate how many tags
            %   from the first data should be found in the second.
            % - The current group information is used for the matching. If
            %   these are numerous the matching will take longer so it is
            %   better for the groups to be processed before performing a
            %   matching proceedure.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu
            
            % Get the original data set from the app.
            data1 = getappdata(app.UITable, 'data1');
            % Get the second data set to merge from the app.
            data2 = getappdata(app.UITable, 'data2');

            % The files names of the first data set will be iterated
            % through regardless if the file or object level is selected.
            files1 = fieldnames(data1.raw);
            % Create an index for the number of potential matches. This
            % will vary depending on the file/object selection.
            ind = 1;
            switch app.Level1.Value
                % User has selected the file level to perform the matching.
                case 'File'
                    % Iterate through the files.
                    for i = 1:length(files1)
                        % Concatenate the group data and add it to the
                        % structure for the table.
                        mergePotential.items1{ind, 1} = strjoin(data1.raw.(files1{i}).groups);
                        ind = ind + 1;
                    end
                % User has selected the object level to perform the 
                % matching.
                case 'Object'
                    % Iterate through the files.
                    for i = 1:length(files1)
                        % Get the object fields to iterate through and
                        % remove the informational ones.
                        obj = fieldnames(data1.raw.(files1{i}));
                        obj(strcmp(obj, 'meta')) = [];
                        obj(strcmp(obj, 'groups')) = [];
                        % Iterate through the objects.
                        for j = 1:length(obj)
                            % Concatenate the groupd data and add it to the
                            % structure for the table.
                            mergePotential.items1{ind, 1} = strjoin(data1.raw.(files1{i}).(obj{j}).groups);
                            ind = ind + 1;
                        end
                    end
            end

            % Get the file names for the second data set as it will be used
            % for both switch cases below.
            files2 = fieldnames(data2.raw);
            % Set a count to use for the table.
            ind = 1;
            switch app.Level2.Value
                % User has selected the file level for the second data set.
                case 'File'
                    % Iterate through the files.
                    for i = 1:length(files2)
                        % Concatenate the group information and add it to
                        % the structure.
                        mergePotential.items2{i, 1} = strjoin(data2.raw.(files2{i}).groups);
                        ind = ind + 1;
                    end
                % User has selected the object level
                case 'Object'
                    % Iterate through the files.
                    for i = 1:length(files2)
                        % Pull the groups and remove the informational
                        % ones.
                        obj = fieldnames(data2.raw.(files2{i}));
                        obj(strcmp(obj, 'meta')) = [];
                        obj(strcmp(obj, 'groups')) = [];
                        % Iterate through the groups
                        for j = 1:length(obj)
                            if isfield(data2.raw.(files2{i}).(obj{j}), 'groups')
                                % If there is group information concate it
                                % and add it to the structure.
                                mergePotential.items2{ind, 1} = strjoin(data2.raw.(files2{i}).(obj{j}).groups);
                            else
                                % If there isn't group information create a
                                % blank as a place holder.
                                mergePotential.items2{ind, 1} = [];
                            end
                            ind = ind + 1;
                        end

                    end
            end

            % It's likely the two lists will have an unequal number of
            % items. They need to be made the same before the structure can
            % be used as a table.
            if length(mergePotential.items1) > length(mergePotential.items2)
                mergePotential.items2(end + 1:length(mergePotential.items1), 1) = {[]};
            elseif length(mergePotential.items1) < length(mergePotential.items2)
                mergePotential.items1(end + 1:length(mergePotential.items2), 1) = {[]};
            end

            % Set the app table to the structure as a table.
            app.UITable.Data = struct2table(mergePotential);
            % Set the column names.
            app.UITable.ColumnName = {'Data Set 1', 'Data Set 2', 'Score'};

        end
        
        function [file, obj, score] = findMatch(app, groups, level, data)
            % [file, obj, score] = findMatch(app, groups, level, data)
            % inputs  - app, required mlapp object.
            %         - groups, the group information to find a match for.
            %         - level, the level to find the match in, 'file' or
            %                  'object'.
            %         - data, the data structure to find the match in.
            % Remarks
            % - This method will search a BAR App data structure at a given
            %   level for matches with given group information. All matches
            %   will be found.
            % - Matches are determined based on the number of group
            %   elements that match. The minimum number is set in the app
            %   interface.
            % - To increase speed it is assumed that all matches will 
            %   always be found within the same item. If the number of
            %   matches count is met or exceeded the matching algorithm
            %   will assume those are the only matches and exit.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            switch level
                % If file is selected search the file level group
                % information for matches.
                case 'file'
                    % Get the file names.
                    files = fieldnames(data.raw);
                    % Initialize an empty array for the matches. This is
                    % usefull for troubleshooting.
                    m = [];
                    % Iterate through the file level.
                    for j = 1:length(files)
                        % Start the number of matches at 0.
                        m(j, 1) = 0;
                        % This will add 1 to the count for each match 
                        % present. 
                        for ii = 1:length(groups)
                            % It will also consider numbers by
                            % converting any potential numbers as text to
                            % doubles. This would match text like 006 with
                            % the number 6.
                            if ~isempty(groups{ii}) && (any(strcmp(data.raw.(files{j}).groups, groups{ii})) || ( ~isempty(str2num(groups{ii})) && any(cell2mat(cellfun(@str2num, data.raw.(files{j}).groups, 'UniformOutput', false)) == str2num(groups{ii}))))
                                m(j, 1) = m(j, 1) + 1;
                            end
                        end
                    end
                    % Zero anything less than the spinner value. This may not be necessary.
                    m(m < app.Spinner.Value) = 0;
                    % Find the index of all the maximums.
                    [m_max, I] = max(m, [], 'all');
                    % Check that the max score was not 0.
                    if m_max ~= 0
                        % Find the row and column indexes that represent the file number and object number.
                        [ind_f, ind_o] = find(m == m_max);
                        % Get the file name.
                        file = files(ind_f);
                        % This is a file match so there is no object name.
                        obj = [];
                        % Save the max score for display.
                        score = m_max;
                    else
                        % If the max was 0 return empty arrays.
                        file = [];
                        obj = [];
                        score = m_max;
                    end
                % If file is selected search the file level group information for matches.
                case 'object'
                    files = fieldnames(data.raw);
                    m = [];
                    for j = 1:length(files)
                        objs = fieldnames(data.raw.(files{j}));
                        objs(strcmp(objs, 'meta')) = [];
                        objs(strcmp(objs, 'groups')) = [];
                        for i = 1:length(objs)
                            m(j, i) = 0;
                            for ii = 1:length(groups)
                                if ~isempty(groups{ii}) && (any(strcmp(data.raw.(files{j}).(objs{i}).groups, groups{ii})) || ( ~isempty(str2num(groups{ii})) && any(cell2mat(cellfun(@str2num, data.raw.(files{j}).(objs{i}).groups, 'UniformOutput', false)) == str2num(groups{ii}))))
                                    m(j, i) = m(j, i) + 1;
                                end
                            end
                        end
                        if any(any(m >= app.Spinner.Value))
                            break
                        end
                    end
                    m(m < app.Spinner.Value) = 0;
                    [m_max, ~] = max(m, [], 'all');
                    if m_max ~= 0
                        [ind_f, ind_o] = find(m >= app.Spinner.Value);
                        file = files(ind_f);
                        for i = 1:length(ind_f)
                            objs = fieldnames(data.raw.(files{ind_f(i)}));
                            objs(strcmp(objs, 'meta')) = [];
                            objs(strcmp(objs, 'groups')) = [];
                            obj{i, 1} = objs{ind_o(i)};
                            score(i, 1) = m_max;
                        end
                    else
                        file = [];
                        obj = [];
                        score = m_max;
                    end
            end

        end
    end
    
    methods (Access = public)
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;

            data1 = getappdata(app.BAR_App.TableFiles, 'data');
            data2 = app.BAR_App.loadData;
            
            setappdata(app.UITable, 'data1', data1)
            setappdata(app.UITable, 'data2', data2)

            updateTable(app)
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: SaveResultsandExitButton
        function SaveResultsandExitSaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UITable, 'data1matched');
            analysis = app.MergeLabel.Text;
            
            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end

        % Button pushed function: RunMatchButton
        function RunMatchButtonPushed(app, event)

            printLog(app, '001', [])

            app.UITable.Data = [];
            
            % This is the first data set that will be used as the base.
            data1 = getappdata(app.UITable, 'data1');
            % This is the second data set that will be inserted into the
            % base.
            data2 = getappdata(app.UITable, 'data2');

            matches = struct;
            ind = 1;

            if strcmp(app.Level1.Value, 'File') && strcmp(app.Level2.Value, 'File')

                files1 = fieldnames(data1.raw);
                for i = 1:length(files1)
                    groups = data1.raw.(files1{i}).groups;
                    [file, ~, score] = findMatch(app, groups, 'file', data2);
                    if ~isempty(file)
                        for j = 1:length(file)
                            matches.item1{ind, 1} = strjoin(groups);
                            matches.item2{ind, 1} = strjoin(data2.raw.(file{j}).groups);
                            matches.score{ind, 1} = score;
                            ind = ind + 1;
                            obj = fieldnames(data2.raw.(file{j}));
                            for k = 1:length(obj)
                                data1.raw.(files1{i}).(obj{k}) = data2.raw.(file{j}).(obj{k});
                                if isfield(data2, 'res')
                                    data1.res.(files1{i}).(obj{k}) = data2.res.(file{j}).(obj{k});
                                end
                            end
                            data2.raw = rmfield(data2.raw, file{j});
                            if isfield(data2, 'res')
                                data2.res = rmfield(data2.raw, file{j});
                            end
                        end
                    else
                        matches.item1{ind, 1} = strjoin(groups);
                        matches.item2{ind, 1} = 'no match found';
                        matches.score{ind, 1} = [];
                        ind = ind + 1;
                    end

                    if rem(i, 10) == 0
                        printLog(app, '003', ['matched ' num2str(i) ' of ' num2str(length(files1))])
                    end

                end

            elseif strcmp(app.Level1.Value, 'File') && strcmp(app.Level2.Value, 'Object')

                files1 = fieldnames(data1.raw);
                for i = 1:length(files1)
                    groups = data1.raw.(files1{i}).groups;
                    [file, obj, score] = findMatch(app, groups, 'object', data2);
                    if ~isempty(file)
                        for j = 1:length(file)
                            matches.item1{ind, 1} = strjoin(groups);
                            matches.item2{ind, 1} = strjoin(data2.raw.(file{j}).(obj{j}).groups);
                            matches.score{ind, 1} = score(j);
                            ind = ind + 1;
                            data1.raw.(files1{i}).(obj{j}) = data2.raw.(file{j}).(obj{j});
                            data2.raw.(file{j}) = rmfield(data2.raw.(file{j}), obj{j});
                            if isfield(data2, 'res')
                                data1.res.(files1{i}).(obj{j}) = data2.res.(file{j}).(obj{j});
                                data2.res.(file{j}) = rmfield(data2.res.(file{j}), obj{j});
                            end
                        end
                    else
                        matches.item1{ind, 1} = strjoin(groups);
                        matches.item2{ind, 1} = 'no match found';
                        matches.score{ind, 1} = [];
                        ind = ind + 1;
                    end

                    if rem(i, 2) == 0
                        printLog(app, '003', ['matched ' num2str(i) ' of ' num2str(length(files1))])
                    end

                end

            end
            
            app.UITable.Data = struct2table(matches);

            setappdata(app.UITable, 'data1matched', data1);

            printLog(app, '002', [])

        end

        % Value changed function: Level1
        function Level1ValueChanged(app, event)
            value = app.Level1.Value;
            switch value
                case 'File'
                    app.Level2.Items = {'File'; 'Object'};
                case 'Object'
                    app.Level2.Items = {'Object'};
            end
            updateTable(app)
        end

        % Value changed function: Level2
        function Level2ValueChanged(app, event)
            value = app.Level2.Value;
            updateTable(app)
        end

        % Button pushed function: ExportMatchingsButton
        function ExportMatchingsButtonPushed(app, event)
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');
            [file, path] = uiputfile('.csv', 'Save As', [app.BAR_App.Database.Value '\Export\Matches' timeStamp '.csv']);
            if isnumeric(file) && file == 0
                return
            else
                writetable(app.UITable.Data,[path file])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.MergeLabel = uilabel(app.UIFigure);
            app.MergeLabel.Text = 'Merge';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Log = uitextarea(app.UIFigure);
            app.SaveResultsandExitButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsandExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsandExitSaveResultsandExitButtonPushed, true);
            app.SaveResultsandExitButton.Text = 'Save Results and Exit';
            app.SelectalevelfromthefirstdatasettomatchDropDownLabel = uilabel(app.UIFigure);
            app.SelectalevelfromthefirstdatasettomatchDropDownLabel.Text = 'Select a level from the first data set to match.';
            app.Level1 = uidropdown(app.UIFigure);
            app.Level1.Items = {'File', 'Object'};
            app.Level1.ValueChangedFcn = createCallbackFcn(app, @Level1ValueChanged, true);
            app.Level1.Value = 'File';
            app.SelectalevelfromtheseconddatasettomatchDropDownLabel = uilabel(app.UIFigure);
            app.SelectalevelfromtheseconddatasettomatchDropDownLabel.Text = 'Select a level from the second data set to match.';
            app.Level2 = uidropdown(app.UIFigure);
            app.Level2.Items = {'File', 'Object'};
            app.Level2.ValueChangedFcn = createCallbackFcn(app, @Level2ValueChanged, true);
            app.Level2.Value = 'File';
            app.RunMatchButton = uibutton(app.UIFigure, 'push');
            app.RunMatchButton.ButtonPushedFcn = createCallbackFcn(app, @RunMatchButtonPushed, true);
            app.RunMatchButton.Text = 'Run Match';
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'What is the minimum number of tags that should be common between sources to form a match?';
            app.Spinner = uispinner(app.UIFigure);
            app.ExportMatchingsButton = uibutton(app.UIFigure, 'push');
            app.ExportMatchingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMatchingsButtonPushed, true);
            app.ExportMatchingsButton.Text = 'Export Matchings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Merge(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end