classdef process_Segment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ExportFiguresSwitch           matlab.ui.control.Switch
        ExportFiguresSwitchLabel      matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        BeforePanel                   matlab.ui.container.Panel
        Log                           matlab.ui.control.TextArea
        SelectDataColumnSpinner       matlab.ui.control.Spinner
        SelectDataColumnSpinnerLabel  matlab.ui.control.Label
        Switch                        matlab.ui.control.Switch
        Tree                          matlab.ui.container.Tree
        Node                          matlab.ui.container.TreeNode
        SegmentallsaveandexitButton   matlab.ui.control.Button
        SelectMethodDropDown          matlab.ui.control.DropDown
        SelectMethodDropDownLabel     matlab.ui.control.Label
        AfterPanel                    matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        DataSegmentationLabel         matlab.ui.control.Label
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['001: selection not valid to plot'];
                case '002'
                    text{length(text) + 1,1} = ['002: time series selection failed to segment'];
                case '003'
                    text{length(text) + 1,1} = ['003: file selection failed to segment'];
                case '004'
                    text{length(text) + 1,1} = ['004: selection is not valid with the segmentation method'];
                case '005'
                    text{length(text) + 1,1} = ['005: segmentation starting...'];
                case '006'
                    text{length(text) + 1,1} = ['006: segmentation complete'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
        
        function x_segmented = segment(app, sel)

            data = getappdata(app.BAR_App.TableFiles, 'data');

            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'processed';
            end

            segmentMethod = app.SelectMethodDropDown.Value;
            
            config = loadConfig(app.BAR_App);
            ind = find(strcmp(config.segmentation_general, segmentMethod));

            printLog(app, '005')

            switch sel
                case 'timeseries'

                    x = data.(type).(app.Tree.SelectedNodes.Parent.Parent.Text).(app.Tree.SelectedNodes.Parent.Text).data.(app.Tree.SelectedNodes.Text);
                    x_segmented = feval(config.segmentation_general{ind + 1}, x, 'timeseries');

                case 'file'
                    
                    file = data.(app.Tree.SelectedNodes.Parent.Text).(app.Tree.SelectedNodes.Text);
                    x_segmented = feval(config.segmentation_general{ind + 1}, file, 'file');

                case 'all'

                    x_segmented = feval(config.segmentation_general{ind + 1}, data, 'all');
                    analysisComplete(app.BAR_App, x_segmented, segmentMethod)

            end

            printLog(app, '006')

        end
        
        function createNodeTree(app)

            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'processed';
            end

            data = getappdata(app.BAR_App.TableFiles, 'data');

            app.Node.Children.delete;

            app.Node.Text = type;
            if ~isfield(data, type)
                return
            end
            files = fieldnames(data.(type));
            ind1 = 1;
            for i = 1:length(files)
                uitreenode(app.Node, 'Text', files{i});
                objs = fieldnames(data.(type).(files{i}));
                objs(strcmp(objs, 'groups')) = [];
                objs(strcmp(objs, 'meta')) = [];
                ind2 = 1;
                for j = 1:length(objs)
                    if strcmp(objs{j}, 'meta')
                        continue
                    end
                    uitreenode(app.Node.Children(ind1), 'Text', objs{j});
                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                    ind3 = 1;
                    for k = 1:length(sigs)
                        if strcmp(sigs{k}, 'freq')
                            continue
                        end
                        uitreenode(app.Node.Children(ind1).Children(ind2), 'Text', sigs{k});
                        ind3 = ind3 + 1;
                    end
                    ind2 = ind2 + 1;
                end
                ind1 = ind1 + 1;
            end
        end
    end
    
    methods (Access = public)
        
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;

            config = loadConfig(app.BAR_App);
            app.SelectMethodDropDown.Items = config.segmentation_general(1:2:end);


            createNodeTree(app)
            
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Callback function: SelectDataColumnSpinner, SelectMethodDropDown, 
        % Tree
        function TreeSelectionChanged(app, event)

            selectedNodes = app.Tree.SelectedNodes;
            n = 0;
            S = selectedNodes;
            while isprop(S,'Parent')
                n = n + 1;
                S = S.Parent;
            end

            data = getappdata(app.BAR_App.TableFiles, 'data');

            % Find what type of data they want to use and set the right
            % case for the data structure.
            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'processed';
            end

            app.SelectDataColumnSpinner.Enable = 1;
            dim = app.SelectDataColumnSpinner.Value;

            % Remove existing plots.
            app.BeforePanel.Children.delete;
            app.AfterPanel.Children.delete;
            
            % Only selected objects will be ploted, not files or entire
            % types.
            if n == 7
                
                % The user can select individual time series to plot, but
                % this may not be what is segmented based on the data type.
                app.SelectDataColumnSpinner.Limits = [1, size(data.(type).(selectedNodes.Parent.Parent.Text).(selectedNodes.Parent.Text).data.(selectedNodes.Text), 2)];

                % Create a hidden figure that will be copied into the app.
                H = figure('visible', 'off');
                ax1 = axes('Parent', H);

                % Plot the selected data.
                plot(ax1, data.(type).(selectedNodes.Parent.Parent.Text).(selectedNodes.Parent.Text).data.(selectedNodes.Text)(:, dim),'k')
                axis(ax1, 'tight')

                % Copy the hidden figure into the app. This allows the same
                % figure to be copied elsewhere in the app or into a pdf or
                % image.
                copyobj(ax1, app.BeforePanel)

                try
                    x = segment(app, 'timeseries');
                    if ischar(x)
                        printLog(app, x)
                        return
                    end
                    

                catch

                    printLog(app, '002')
                    return

                end

                I = figure('visible', 'off');

                % Plot the selected data.

                N = length(x);
                for i = 1:length(x)
                    ax2(i) = subplot(N,1,i);
                    plot(x{i},'k')
                    set(ax2(i), 'xticklabels', []);
                    set(ax2(i), 'yticklabels', []);
                    axis tight

                    % Copy the hidden figure into the app. This allows the same
                    % figure to be copied elsewhere in the app or into a pdf or
                    % image.
                    copyobj(ax2(i), app.AfterPanel)
                end

            elseif n == 5

                try
                    x = segment(app, 'file');
                    if ischar(x)
                        printLog(app, x)
                        return
                    end
                    

                catch

                    printLog(app, '003')
                    return

                end

                H = figure('visible', 'off');
                ax1 = axes('Parent', H);

                % Plot the selected data.
                plot(ax1, x.raw,'k')
                hold on
                turns = fieldnames(x.res.Segment);
                y = [min(x.raw); max(x.raw)];
                for i = 1:length(turns)
                    plot(ax1, [x.res.Segment.(turns{i}).startFrame; x.res.Segment.(turns{i}).startFrame], y, 'r')
                    plot(ax1, [x.res.Segment.(turns{i}).endFrame; x.res.Segment.(turns{i}).endFrame], y, 'b')
                end
                hold off
                axis(ax1, 'tight')

                % Copy the hidden figure into the app. This allows the same
                % figure to be copied elsewhere in the app or into a pdf or
                % image.
                copyobj(ax1, app.BeforePanel)

                I = figure('visible', 'off');

                % Plot the selected data.

                N = length(x.processed);
                for i = 1:N
                    ax2(i) = subplot(N,1,i);
                    plot(x.processed{i},'k')
                    set(ax2(i), 'xticklabels', []);
                    set(ax2(i), 'yticklabels', []);
                    axis tight

                    % Copy the hidden figure into the app. This allows the same
                    % figure to be copied elsewhere in the app or into a pdf or
                    % image.
                    copyobj(ax2(i), app.AfterPanel)
                end

            else
                % If something besides an object is selected nothing will
                % be plot.
                app.SelectDataColumnSpinner.Enable = 0;
                printLog(app, '001')

                return
            end

            if strcmp(app.ExportFiguresSwitch.Value, 'Yes')

                J = figure('visible', 'off', 'units', 'normalized', 'position', [0 0 1 1]);

                N = length(app.AfterPanel.Children);

                ax3(1) = subplot(N, 2, [1:2:N*2]);
                ax3(1,2) = copyobj(app.BeforePanel.Children, J);
                ax3(1,2).Position = ax3(1,1).Position;
                delete(ax3(1,1))

                for i = 1:N
                    ax3(1 + i, 1) = subplot(N, 2, [2*i]);
                    ax3(1 + i, 2) = copyobj(app.AfterPanel.Children(N - i + 1), J);
                    ax3(1 + i, 2).Position = ax3(1 + i, 1).Position;
                    delete(ax3(1 + i, 1))
                end

            end

            m_FigurePath = app.BAR_App.Database.Value;
            % The data is saved automatically using the name of the
            % analysis method used. This could also be a process method.
            if n == 5
                saveas(J, [m_FigurePath '\Figures\Segment_Figure01_'  selectedNodes.Text '.jpg'])
            end

        end

        % Button pushed function: SegmentallsaveandexitButton
        function SegmentallsaveandexitButtonPushed(app, event)
            try
                data = segment(app, 'all');
                if ischar(data)
                    printLog(app, data)
                    return
                end
            catch
                printLog(app, '002')
                return
            end

            analysisComplete(app.BAR_App, data, 'SegmentTurns')

            delete(app)
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            createNodeTree(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.DataSegmentationLabel = uilabel(app.UIFigure);
            app.DataSegmentationLabel.Text = 'Data Segmentation';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.AfterPanel = uipanel(app.UIFigure);
            app.AfterPanel.Title = 'After';
            app.SelectMethodDropDownLabel = uilabel(app.UIFigure);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.UIFigure);
            app.SelectMethodDropDown.Items = {};
            app.SelectMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SelectMethodDropDown.Value = {};
            app.SegmentallsaveandexitButton = uibutton(app.UIFigure, 'push');
            app.SegmentallsaveandexitButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentallsaveandexitButtonPushed, true);
            app.SegmentallsaveandexitButton.Text = 'Segment all, save and exit.';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Raw';
            app.SelectDataColumnSpinnerLabel = uilabel(app.UIFigure);
            app.SelectDataColumnSpinnerLabel.Text = 'Select Data Column';
            app.SelectDataColumnSpinner = uispinner(app.UIFigure);
            app.SelectDataColumnSpinner.Limits = [1 1000000];
            app.SelectDataColumnSpinner.RoundFractionalValues = 'on';
            app.SelectDataColumnSpinner.ValueDisplayFormat = '%11.0g';
            app.SelectDataColumnSpinner.ValueChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.SelectDataColumnSpinner.Enable = 'off';
            app.SelectDataColumnSpinner.Value = 1;
            app.Log = uitextarea(app.UIFigure);
            app.BeforePanel = uipanel(app.UIFigure);
            app.BeforePanel.Title = 'Before';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Selected objects will be segmented and previewed to the left.';
            app.ExportFiguresSwitchLabel = uilabel(app.UIFigure);
            app.ExportFiguresSwitchLabel.Text = 'Export Figures';
            app.ExportFiguresSwitch = uiswitch(app.UIFigure, 'slider');
            app.ExportFiguresSwitch.Items = {'No', 'Yes'};
            app.ExportFiguresSwitch.Value = 'No';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Segment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end