classdef process_Treatment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Log                        matlab.ui.control.TextArea
        SelectStepDropDown_2       matlab.ui.control.DropDown
        SelectStepDropDown_2Label  matlab.ui.control.Label
        RemoveStepButton           matlab.ui.control.Button
        AddStepButton              matlab.ui.control.Button
        SelectStepDropDown         matlab.ui.control.DropDown
        SelectStepDropDownLabel    matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        ProcessButton              matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        DataTreatmentLabel         matlab.ui.control.Label
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['resample is not currently designed for non-integer resampling, rounding the sampling frequency to the nearest integer'];
                case '002'
                    text{length(text) + 1,1} = ['add step: ' newText];
                case '003'
                    text{length(text) + 1,1} = ['removed step: ' newText];
                case '004'
                    text{length(text) + 1,1} = ['processing started'];
                case '005'
                    text{length(text) + 1,1} = ['processing complete'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
        
        function createGlobalProperties(app)

            data = getappdata(app.UITable, 'data');

            files = fieldnames(data.raw);
            ind = 0;
            for i = 1:length(files)
                sigs = fieldnames(data.raw.(files{i}));
                for j = 1:length(sigs)
                    if isfield(data.raw.(files{i}).(sigs{j}), 'freq')
                        if ~isinteger(data.raw.(files{i}).(sigs{j}).freq)
                            printLog(app, '001', [])
                        end
                        globalProperties.freq(ind + 1, 1) = round(data.raw.(files{i}).(sigs{j}).freq);
                        ind = ind + 1;
                    else
                        globalProperties.freq(ind + 1, 1) = NaN;
                    end
                end
            end

            setappdata(app.UITable, 'globalProperties', globalProperties)
            
        end

        function identifyTreatmentOptions(app)
            % Use the directory entered into the app to find the load 
            % scripts.
            m_files = dir(app.BAR_App.FilesProcess.Value);

            % Look for files within the directory. If the file names
            % contain the word 'Treatment' and a third part save them 
            % within the app and configuration.
            processOptions = [];
            ind = 1;
            for i = 1:height(m_files)
                % MATLAB creates temporary asv files of m-files that are
                % currently being edited. There are not viable options.
                if contains(m_files(i).name, 'process') && contains(m_files(i).name, 'Treatment') && ~contains(m_files(i).name, 'asv')
                    [~, name] = fileparts(m_files(i).name);
                    parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                    if ~isempty(parts{3})
                        processOptions{ind} = parts{3}{1};
                        ind = ind + 1;
                    end
                end
            end

            % If there are no available options or if the directory is 
            % misleading nothing will be found. The object in the app 
            % can't be set to empty.
            if ~isempty(processOptions)
                app.SelectStepDropDown.Items = processOptions;
            end
            
        end
    end
    
    methods (Access = public)
        
        function addStep(app, properties)
            steps = app.UITable.Data;

            if isempty(steps)
                steps.Number = num2str(1);
                steps.Treatment{1} = properties.type;
                steps.Parameters{1} = properties;
                app.UITable.Data = struct2table(steps);
                app.UITable.ColumnName = fieldnames(steps);
            else
                steps = [steps;{num2str(height(steps) + 1), properties.type, properties}];
                app.UITable.Data = steps;
            end

            app.SelectStepDropDown_2.Items = join([steps.Number(:), steps.Treatment(:)]);

            printLog(app, '002', properties.type)

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;
            
            setappdata(app.UITable, 'data', data)

            identifyTreatmentOptions(app)

            createGlobalProperties(app)
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Callback function
        function SaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UITable, 'data');
            analysis = app.DataTreatmentLabel.Text;

            setappdata(app.BAR_App.TableFiles, 'data', data)
            
            analysisComplete(app.BAR_App, analysis)

            delete(app)
        end

        % Button pushed function: AddStepButton
        function AddStepButtonPushed(app, event)
            
            globalProperties = getappdata(app.UITable, 'globalProperties');
            
            feval(['process_Treatment_' app.SelectStepDropDown.Value], app, globalProperties);
            
        end

        % Button pushed function: RemoveStepButton
        function RemoveStepButtonPushed(app, event)

            steps = app.UITable.Data;

            step2remove = app.SelectStepDropDown_2.Value;

            ind = strfind(step2remove, ' ');
            n = str2double(step2remove(1:ind(1) - 1));

            steps(n,:) = [];
            steps.Number = cellstr(num2str([1:height(steps)]'));

            app.UITable.Data = steps;
            app.SelectStepDropDown_2.Items = join([steps.Number(:), steps.Treatment(:)]);

            printLog(app, '003', step2remove)

        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

            printLog(app, '004')
            
            steps = app.UITable.Data;

            data = getappdata(app.UITable, 'data');

            globalProperties = getappdata(app.UITable, 'globalProperties');

            type = 'raw';

            files = fieldnames(data.raw);
            for j = 1:length(files)
                obj = fieldnames(data.raw.(files{j}));
                for k = 1:length(obj)
                    if strcmp(obj{k}, 'meta')
                        data.processed.(files{j}).meta = data.raw.(files{j}).meta;
                    else
                        if isfield(data.raw.(files{j}).(obj{k}), 'freq')
                            data.processed.(files{j}).(obj{k}).freq = data.raw.(files{j}).(obj{k}).freq;
                        end
                    end
                end
            end

            for i = 1:height(steps)
                ind = 1;
                for j = 1:length(files)
                    obj = fieldnames(data.(type).(files{j}));
                    for k = 1:length(obj)
                        if strcmp(obj{k}, 'meta')
                            continue
                        else
                            sigs = fieldnames(data.(type).(files{j}).(obj{k}).data);
                            for ii = 1:length(sigs)
                                switch steps.Treatment{i}
                                    case 'Low-pass Filter'
                                        [b, a] = butter(steps.Parameters{i}.order, steps.Parameters{i}.cf/(globalProperties.freq(ind)/2));
                                        data.processed.(files{j}).(obj{k}).data.(sigs{ii}) = FiltFiltM(b, a, data.(type).(files{j}).(obj{k}).data.(sigs{ii}), 1);
                                    case 'Resample'
                                        temp = data.(type).(files{j}).(obj{k}).data.(sigs{ii});
                                        data.(type).(files{j}).(obj{k}).data.(sigs{ii}) = [];
                                        for jj = 1:size(temp, 2)
                                            shift = mean(temp(:, jj));
                                            temp2 = resample(temp(:,jj) - shift, steps.Parameters{i}.freq, round(data.(type).(files{j}).(obj{k}).freq));
                                            data.processed.(files{j}).(obj{k}).data.(sigs{ii})(:, jj) = temp2 + shift;
                                        end
                                        data.processed.(files{j}).(obj{k}).freq = steps.Parameters{i}.freq;
                                end
                            end
                            ind = ind + 1;
                        end
                    end
                end
                if strcmp(type,'raw')
                    type = 'processed';
                end
            end

            analysis = 'Treatment';
            analysisComplete(app.BAR_App, data, analysis)

            printLog(app, '005')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.DataTreatmentLabel = uilabel(app.UIFigure);
            app.DataTreatmentLabel.Text = 'Data Treatment';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.SelectStepDropDownLabel = uilabel(app.UIFigure);
            app.SelectStepDropDownLabel.Text = 'Select Step';
            app.SelectStepDropDown = uidropdown(app.UIFigure);
            app.SelectStepDropDown.Items = {};
            app.SelectStepDropDown.Value = {};
            app.AddStepButton = uibutton(app.UIFigure, 'push');
            app.AddStepButton.ButtonPushedFcn = createCallbackFcn(app, @AddStepButtonPushed, true);
            app.AddStepButton.Text = 'Add Step';
            app.RemoveStepButton = uibutton(app.UIFigure, 'push');
            app.RemoveStepButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveStepButtonPushed, true);
            app.RemoveStepButton.Text = 'Remove Step';
            app.SelectStepDropDown_2Label = uilabel(app.UIFigure);
            app.SelectStepDropDown_2Label.Text = 'Select Step';
            app.SelectStepDropDown_2 = uidropdown(app.UIFigure);
            app.SelectStepDropDown_2.Items = {};
            app.SelectStepDropDown_2.Value = {};
            app.Log = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = process_Treatment(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end