classdef review_General < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotensembleCheckBox           matlab.ui.control.CheckBox
        ExportPlotButton               matlab.ui.control.Button
        v10Label                       matlab.ui.control.Label
        DataTypeDropDown               matlab.ui.control.DropDown
        DataTypeDropDownLabel          matlab.ui.control.Label
        GetadditionalaxesfromDropDown  matlab.ui.control.DropDown
        GetadditionalaxesfromDropDownLabel  matlab.ui.control.Label
        UpdatePlotButton               matlab.ui.control.Button
        MetaValueListBox               matlab.ui.control.ListBox
        MetaValueListBoxLabel          matlab.ui.control.Label
        MetaFieldListBox               matlab.ui.control.ListBox
        MetaFieldListBoxLabel          matlab.ui.control.Label
        DimensionListBox               matlab.ui.control.ListBox
        DimensionListBoxLabel          matlab.ui.control.Label
        SignalsListBox                 matlab.ui.control.ListBox
        SignalsListBoxLabel            matlab.ui.control.Label
        ObjectsListBox                 matlab.ui.control.ListBox
        ObjectsListBoxLabel            matlab.ui.control.Label
        FilesListBox                   matlab.ui.control.ListBox
        FilesListBoxLabel              matlab.ui.control.Label
        PlottypeDropDown               matlab.ui.control.DropDown
        PlottypeDropDownLabel          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        Log                            matlab.ui.control.TextArea
        Image                          matlab.ui.control.Image
        GeneralReivewLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        BAR_App   % Main app object
    end

    properties (Access = public)
    end

    methods (Access = private)

        function printLog(app, code, newText)
            % printLog(app, code, newText)
            % inputs  - app, required mlapp object.
            %         - code, an error code used to identify the source of
            %           the error and organize the errors available.
            %         - newText, a string that may be inserted into the
            %                    message.
            % Remarks
            % - This method will print messages to the log of the Merge
            %   module. The error codes are unique to each message so they
            %   can be found and referenced easier.
            % Future Work
            % - This log should be printed and saved to a file location
            %   each time the BAR App is run.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current text within the log.
            text = app.Log.Value;
            % Match the error code with the message and update the text for
            % the log.
            switch code
                case '001'
                    text{length(text) + 1,1} = ['selection not valid to plot'];
                case '002'
                    text{length(text) + 1,1} = ['updating selections'];
                case '003'
                    text{length(text) + 1,1} = ['update complete'];
            end
            % Update the log.
            app.Log.Value = text;
            % Scroll to the bottom so the most recent message is visible.
            scroll(app.Log,'bottom')
            % Make sure the app updates before the app proceeds.
            drawnow
        end

        function UpdatePlot(app)
            % UpdatePlot(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This is the main method of the app that will create the plot according to the users selection.
            % - There is an incompatibility with UIFigures and Figures that prevents the same figure from being copied or moved around. So this method
            %   will create an invisible figure outside the app and then copy it into the app.
            % Future Work
            % - There may be some efficiences that could be made with the use of subroutines. This method has become somewhat complex and long.
            % - There is currently no way for time information to be plotted. It could be done by default for 1D selections if there is a frequency
            %   variable.
            % - Images are not displayed very well as MATLAB places a large amount of grey space around them. This makes the images smaller than
            %   desired.
            % - The plot could display axis titles based on the users selection.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % The data is pulled from the BAR app.
            data = getappdata(app.BAR_App.TableFiles, 'data');

            
            % Delete the current figure.
            app.Panel.Children.delete

            % This may solve some pixilation problems on computers when displaying images.
            if strcmp(app.PlottypeDropDown.Value, 'Image')
                opengl hardware
            end

            % The figure methods will be found dynamically.

            % Find the load function that matches the selected file extension and equipment type.
            % Get the load functions for the first directory.
            filesReview = dir(app.BAR_App.FilesReview.Value{1});
            % Get the load functions for the additional directories if there are more.
            if length(app.BAR_App.FilesReview.Value) > 1
                for i = 2:length(app.BAR_App.FilesReview.Value)
                    filesReview = [filesReview; dir(app.BAR_App.FilesReview.Value{i})];
                end
            end

            % Find the index where the filename contains the target file extension and the equipment type and is not an asv-file.
            ind3 = find(contains({filesReview.name}', app.PlottypeDropDown.Value) & ~contains({filesReview.name}', 'asv'), 1);

            % Exit if no file was found.
            if isempty(ind3)
                return
            end

            % Find the index of '.m' which will be present in all m- and mlapp-files.
            ind4 = strfind(filesReview(ind3).name, '.m');

            % Dynamically run the method and get the figure.
            switch app.PlottypeDropDown.Value
                case {'1D', '2D', '3D', 'Image', 'Histogram'}
                    % Organize the data to plot for these methods.
                    plotData = getData(app, data);
                    % If nothing as been put into the plot array exit and leave an empty figure.
                    if isempty(plotData{1})
                        return
                    end
                    H = feval(filesReview(ind3).name(1:ind4 - 1), plotData);
                otherwise
                    H = feval(filesReview(ind3).name(1:ind4 - 1), app, data);
            end

            % Find the number of axes in the figure.
            n = zeros(length(H.Children), 1);
            for i = 1:length(H.Children)
                if strcmp(get(H.Children(i), 'type'), 'axes')
                    n(i) = 1;
                end
            end

            % For a single plot copy the axes to the app.
            if sum(n) == 1
                for i = 1:length(n)
                    if n(i)
                        copyobj(H.Children, app.Panel)
                    end
                end
            else
                % For multiple axes plot them in two columns.
                m = ceil(n/2);
                for i = 1:length(n)
                    if n(i)
                        copyobj(H.Children, app.Panel)
                    end
                end
            end

        end

        function createLists(app)
            % createLists(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method will populate the various dropdown lists in the app. In the method a very long list will be made, and then only the unique
            %   ones will be used. This allows items that are present in some levels but not others to still be used.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the current data.
            data = getappdata(app.BAR_App.TableFiles, 'data');

            if isempty(data)
                printLog(app.BAR_App ,'030')
                delete(app)
                return
            end

            % The lists will be recreated if the dropdown is changed.
            switch app.DataTypeDropDown.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
                case 'Results'
                    type = 'res';
            end

            % Create list for Files.
            files = fieldnames(data.(type));
            % Add all the files as an option at the top.
            app.FilesListBox.Items = [{'all'}; files];

            % Create list for Objects, Signals and Dimensions.
            % Create initial empty lists.
            objList = [];
            sigList = [];
            d = [];
            % Iterate through the files.
            for i = 1:length(files)
                % Get the list of objects and remove the informational ones.
                obj = fieldnames(data.(type).(files{i}));
                obj(strcmp(obj, 'meta')) = [];
                obj(strcmp(obj, 'groups')) = [];
                % Add the objects for the current file to the list.
                objList = [objList; obj];
                % Iterate through the objects.
                for j = 1:length(obj)
                    % Get the signal names.
                    sig = fieldnames(data.(type).(files{i}).(obj{j}).data);
                    % Add the signals for the current object to the list.
                    sigList = [sigList; sig];
                    % For each signal determine how many dimensions it has and add it to the list.
                    for k = 1:length(sig)
                        d = [d; (1:size(data.(type).(files{i}).(obj{j}).data.(sig{k}), 2))'];
                    end
                end
            end
            % Set the list boxes to the unique values while adding an 'all' option to the top.
            app.ObjectsListBox.Items = [{'all'}; unique(objList)];
            app.SignalsListBox.Items = [{'all'}; unique(sigList)];
            % The dimensions do need to be converted to strings and then to cells to add to the list box.
            app.DimensionListBox.Items = [{'all'}; cellstr(num2str(unique(d)))];

            % Identify the fields for meta data.
            metaListFields = [];
            % Iterate through the files.
            for i = 1:length(files)
                % Check if there is meta data.
                if isfield(data.(type).(files{i}), 'meta')
                    % Get the fields in the meta data and add to the list.
                    metaListFields = [metaListFields; fieldnames(data.(type).(files{i}).meta)];
                end
            end
            % Set the list box to the unique values.
            app.MetaFieldListBox.Items = [{'none'}; unique(metaListFields)];

        end

        function identifyFigureOptions(app)
            % identifySegmentOptions(app)
            % inputs  - app, required mlapp object.
            % Remarks
            % - This method searches the Process folder for valid files that would be used to segment data within the app. These files need to have a
            %   name process_Segment*.m. The results will be saved to the configuration file and made selectable in the app.
            % - The goal of this function was to make the addition of new functions easier.
            % - The idea of processing is to take an input and create a new ouput of similar size and scale.
            % Future Work
            % - None
            % Dec 2022 - Created by Ben Senderling, bsender@bu.edu

            % Load the existing configuration so it can be updated.
            config = loadConfig(app.BAR_App);

            % Update the configuration with the directory entered into the app.
            config.options.filesreview = app.BAR_App.FilesReview.Value;

            % Empty the available treatment files from the saved configuration.
            config.options.figure = [];
            ind = 1;

            for j = 1:length(app.BAR_App.FilesReview.Value)
                % Use the directory entered into the app to find the processing scripts.
                m_files = dir(app.BAR_App.FilesReview.Value{j});

                % Look for files within the directory. If the file names contain the word 'process' save them within the app and configuration.
                for i = 1:height(m_files)
                    % MATLAB creates temporary asv files of m-files that are currently being edited. There are not viable options.
                    if contains(m_files(i).name, 'figure') && ~contains(m_files(i).name, 'asv')

                        [~, name] = fileparts(m_files(i).name);
                        parts = textscan(name, '%s%s%s', 'Delimiter', '_');
                        config.options.figure{ind,1} = parts{2}{1};
                        ind = ind + 1;
                    end
                end
            end

            % If there are no segment files or if the directory is misleading because files have been moved or the app was moved nothing will be
            % found. The object in the app can't be set to empty.
            if ~isempty(config.options.figure)
                app.PlottypeDropDown.Items = config.options.figure;
            end

            % Save the configuration with the updated directory and or file
            % extensions and formats.
            saveConfig(app.BAR_App, config)

        end

        function plotData = getData(app, data)

            % Find if the Raw, Processed or Results data should be displayed.
            switch app.DataTypeDropDown.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
                case 'Results'
                    type = 'res';
            end

            % Get the selected dimensions and convert them to numbers.
            dim = arrayfun(@str2double, app.DimensionListBox.Value);
            % If no dimension is selected use the first one.
            if isempty(dim)
                dim = 1;
            end
            % If 'all' is selected the dim will be a NaN. So pull all the available options, convert them to numbers and remove the 'all' turned NaN.
            if isnan(dim)
                dim = arrayfun(@str2double, app.DimensionListBox.Items);
                dim(isnan(dim)) = [];
            end

            % Initialize the array of data to plot.
            plotData = [];
            plotData{1} = [];
            % This flag is used to help control how data is assigned to different dimensions across structure levels.
            flag = 0;
            % Create an index for the dimensions.
            indDim = 0;
            % Start iterating through the files.
            files = fieldnames(data.(type));
            for i = 1:length(files)
                % Proceed if the current file is selected, nothing is selected, or all is selected.
                if any(strcmp(app.FilesListBox.Value, files{i})) || isempty(app.FilesListBox.Value) || any(strcmp(app.FilesListBox.Value, 'all'))

                    % Proceed if there is no meta data, or if no meta fields are selected, or if the selected meta field is not an option for the
                    % current file or none is selected, and if the meta value is equal to the meta field's value for the current file or none is
                    % selected for the meta value.
                    if ~isfield(data.(type).(files{i}), 'meta') || isempty(app.MetaFieldListBox.Value) || (isfield(data.(type).(files{i}).meta, app.MetaFieldListBox.Value{1}) || strcmp(app.MetaFieldListBox.Value{1}, 'none')) && (strcmp(data.(type).(files{i}).meta.(app.MetaFieldListBox.Value{1}), app.MetaValueListBox.Value) || strcmp(app.MetaValueListBox.Value, 'none'))

                        if flag == 0
                            % If files are selected to use as an additional dimension add 1, else set the dimension count to 0.
                            if ~strcmp(app.GetadditionalaxesfromDropDown.Value, 'Files')
                                indDim = 0;
                            else
                                indDim = indDim + 1;
                                % If we are in a dimension over 1 set a flag so the dimension count is not overwitten latter.
                                flag = 1;
                            end
                        end

                        % Get the object names and remove the informational fields.
                        obj = fieldnames(data.(type).(files{i}));
                        obj(strcmp(obj, 'meta')) = [];
                        obj(strcmp(obj, 'groups')) = [];
                        % Start iterating through the objects.
                        for j = 1:length(obj)
                            % Proceed if the current object is selected, or if nothing is selected, or if all is selected.
                            if any(strcmp(app.ObjectsListBox.Value, obj{j})) || isempty(app.ObjectsListBox.Value) || any(strcmp(app.ObjectsListBox.Value, 'all'))

                                if flag == 0
                                    % If objects are selected to use as an additional dimension at 1, else set the dimension count to 0.
                                    if ~strcmp(app.GetadditionalaxesfromDropDown.Value, 'Objects')
                                        indDim = 0;
                                    else
                                        indDim = indDim + 1;
                                        % If we are in a dimension over 1 set a flag so the dimension count is not overwitten latter.
                                        flag = 1;
                                    end
                                end

                                % Get the signal names.
                                sig = fieldnames(data.(type).(files{i}).(obj{j}).data);
                                % Start iterating through the signals.
                                for k = 1:length(sig)
                                    % Proceed if the current signal is selected, or if no signal is selected, or if all is selected.
                                    if any(strcmp(app.SignalsListBox.Value, sig{k})) || isempty(app.SignalsListBox.Value) || any(strcmp(app.SignalsListBox.Value, 'all'))
                                        % Iterate through the selected dimensions.

                                        if flag == 0
                                            % If signals are selected to use as an additional dimension at 1, else set the dimension count to 0.
                                            if ~strcmp(app.GetadditionalaxesfromDropDown.Value, 'Signals')
                                                indDim = 0;
                                            else
                                                indDim = indDim + 1;
                                                % If we are in a dimension over 1 set a flag so the dimension count is not overwitten latter.
                                                flag = 1;
                                            end
                                        end
                                        % Iterate through the selected dimensions.
                                        for ii = 1:length(dim)

                                            % The selected data may not all have the same number of dimensions. Check them and if it is exceeded skip
                                            % the iteration.
                                            if size(data.(type).(files{i}).(obj{j}).data.(sig{k}), 2) >= dim(ii)


                                                if flag == 0
                                                    % If dimensions are selected to use as an additional dimension at 1, else set the dimension count to
                                                    % 0.
                                                    if ~strcmp(app.GetadditionalaxesfromDropDown.Value, 'Dimension')
                                                        indDim = 0;
                                                    else
                                                        indDim = indDim + 1;
                                                    end
                                                end

                                                if strcmp(app.GetadditionalaxesfromDropDown.Value, 'None')
                                                    indDim = 1;
                                                end

                                                % Find the index of the column in the plotting array to assign the data too.
                                                if size(plotData, 2) < indDim
                                                    % If this is the first data for this dimension the column is 1.
                                                    ind = 1;
                                                else
                                                    % Else if there is already data find the number of columns and add 1 to get the index.
                                                    ind = size(plotData{indDim}, 2) + 1;
                                                end

                                                % Get the data to add to the plot array.
                                                newData = data.(type).(files{i}).(obj{j}).data.(sig{k})(:, dim(ii));

                                                % Assign the data to the plot array accounting for size differences.
                                                if size(plotData, 2) < indDim || isempty(plotData{indDim})
                                                    % If there is no data for the dimension or the array is empty, as would be the case for the first
                                                    % data.
                                                    plotData{indDim}(:, ind) = newData;
                                                elseif size(newData, 1) > size(plotData{indDim}, 1)
                                                    % If the new data is larger the bottom of the existing data is padded with NaNs.
                                                    plotData{indDim} = [plotData{indDim}; NaN*ones(size(newData, 1) - size(plotData{indDim}, 1), size(plotData{indDim}, 2))];
                                                    plotData{indDim}(:, ind) = newData;
                                                elseif size(newData, 1) < size(plotData{indDim}, 1)
                                                    % If the new data is larger the new data is padded with NaNs to match the existing data.
                                                    plotData{indDim}(:, ind) = [newData; NaN*ones(size(plotData{indDim}, 1) - size(newData, 1), 1)];
                                                else
                                                    % If the sizes match add the data as is.
                                                    plotData{indDim}(:, ind) = newData;
                                                end

                                            end

                                            % If dimension is set as the additional axis reset the flag, all the dimensions have been iterated
                                            % through.
                                            if flag == 1 && strcmp(app.GetadditionalaxesfromDropDown.Value, 'Dimension')
                                                flag = 0;
                                            end

                                        end

                                    end
                                    % If signals is set as the additional dimension reset the flag, all the signals have been iterated through.
                                    if flag == 1 && strcmp(app.GetadditionalaxesfromDropDown.Value, 'Signals')
                                        flag = 0;
                                    end
                                end

                            end
                            % If objects is set as the additional dimension reset the flag, all the objects have been iterated through.
                            if flag == 1 && strcmp(app.GetadditionalaxesfromDropDown.Value, 'Objects')
                                flag = 0;
                            end
                        end
                    end
                end
                % If files is set as the additional dimension reset the flag, all the files have been iterated through.
                if flag == 1 && strcmp(app.GetadditionalaxesfromDropDown.Value, 'Files')
                    flag = 0;
                end
            end

        end
    end

    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp)
            % StartupFcn(app, mainapp)
            % inputs  - app, required mlapp object.
            %         - mainapp, BAR App input.
            % Remarks
            % - This is the required startup method that will initialize the app.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;

            % Currently the configuration file controls what plot options are allowed.
            identifyFigureOptions(app)

            % Populate the list boxes.
            createLists(app)

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Callback function
        function SaveResultsandExitButtonPushed(app, event)

            delete(app)
        end

        % Value changed function: MetaFieldListBox
        function MetaFieldListBoxValueChanged(app, event)
            % MetaFieldListBoxValueChanged(app, event)
            % inputs  - app, required mlapp object.
            %         - event, information about the app during the change in the listbox value.
            % Remarks
            % - This method will update the available selection for the meta data values depending on what meta data field was selected.
            % Future Work
            % - None.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the value of the selected meta data field.
            value = app.MetaFieldListBox.Value;
            % Get the current data.
            data = getappdata(app.BAR_App.TableFiles, 'data');
            % Determine what type of data to use.
            switch app.DataTypeDropDown.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'pro';
                case 'Results'
                    type = 'res';
            end

            % Initialize an empty array for the list.
            metaListValues = [];
            % Get the file names.
            files = fieldnames(data.(type));
            % Iterate through the file names.
            for i = 1:length(files)
                % Check if the selected meta data field is an option for the current file.
                if isfield(data.(type).(files{i}).meta, value{1})
                    % Get the meta data values for the field and add them to the list.
                    metaListValues = [metaListValues; {data.(type).(files{i}).meta.(value{1})}];
                end
            end
            % Meta data may be highly variable so a try-catch is used.
            try
                % Set the list box values to the unique list items.
                app.MetaValueListBox.Items = unique(metaListValues);
            catch
                % If it fails set it to an empty array.
                app.MetaValueListBox.Items = {};
            end
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            UpdatePlot(app)
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            createLists(app)
        end

        % Button pushed function: ExportPlotButton
        function ExportPlotButtonPushed(app, event)
            % ExportPlotButtonPushed(app, event)
            % Inputs  - app, required mlapp input.
            %         - event, information about the app from the button press.
            % Remarks
            % - This method allows the user the export the current figure.
            % Future Work
            % - Perhaps there is an elegant way to export many figures without going through each of them. Currently that is best done through the
            %   analysis or process methods.
            % Nov 2022 - Created by Ben Senderling, bsender@bu.edu

            % Get the database path.
            m_resultsPath = app.BAR_App.Database.Value;

            % Construct a timestamp to use in the file name.
            timeStamp = datestr(datetime('now'),'yyyymmddHHMMss');

            % Get a user entered file name and suggest the coded version.
            [file, path] = uiputfile('.jpg', 'Save As', [m_resultsPath '\Figures\Figure' timeStamp '.jpg']);
            % Exit if no filename was entered.
            if isnumeric(file) && file == 0
                return
            else
                % Create a parent object to copy the figure into.
                H = figure('visible', 'off');
                % Copy the figure from the app.
                copyobj(app.Panel.Children, H)
                printLog(app, '012')
                % Save the figure.
                saveas(H, [path file])
                printLog(app, '013')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.GeneralReivewLabel = uilabel(app.UIFigure);
            app.GeneralReivewLabel.Text = 'General Reivew';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.Log = uitextarea(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.PlottypeDropDownLabel = uilabel(app.UIFigure);
            app.PlottypeDropDownLabel.Text = 'Plot type';
            app.PlottypeDropDown = uidropdown(app.UIFigure);
            app.PlottypeDropDown.Items = {};
            app.PlottypeDropDown.Value = {};
            app.FilesListBoxLabel = uilabel(app.UIFigure);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesListBox = uilistbox(app.UIFigure);
            app.FilesListBox.Items = {};
            app.FilesListBox.Multiselect = 'on';
            app.FilesListBox.Value = {};
            app.ObjectsListBoxLabel = uilabel(app.UIFigure);
            app.ObjectsListBoxLabel.Text = 'Objects';
            app.ObjectsListBox = uilistbox(app.UIFigure);
            app.ObjectsListBox.Items = {};
            app.ObjectsListBox.Multiselect = 'on';
            app.ObjectsListBox.Value = {};
            app.SignalsListBoxLabel = uilabel(app.UIFigure);
            app.SignalsListBoxLabel.Text = 'Signals';
            app.SignalsListBox = uilistbox(app.UIFigure);
            app.SignalsListBox.Items = {};
            app.SignalsListBox.Multiselect = 'on';
            app.SignalsListBox.Value = {};
            app.DimensionListBoxLabel = uilabel(app.UIFigure);
            app.DimensionListBoxLabel.Text = 'Dimension';
            app.DimensionListBox = uilistbox(app.UIFigure);
            app.DimensionListBox.Items = {};
            app.DimensionListBox.Multiselect = 'on';
            app.DimensionListBox.Value = {};
            app.MetaFieldListBoxLabel = uilabel(app.UIFigure);
            app.MetaFieldListBoxLabel.Text = 'Meta Field';
            app.MetaFieldListBox = uilistbox(app.UIFigure);
            app.MetaFieldListBox.Items = {};
            app.MetaFieldListBox.Multiselect = 'on';
            app.MetaFieldListBox.ValueChangedFcn = createCallbackFcn(app, @MetaFieldListBoxValueChanged, true);
            app.MetaFieldListBox.Value = {};
            app.MetaValueListBoxLabel = uilabel(app.UIFigure);
            app.MetaValueListBoxLabel.Text = 'Meta Value';
            app.MetaValueListBox = uilistbox(app.UIFigure);
            app.MetaValueListBox.Items = {};
            app.MetaValueListBox.Multiselect = 'on';
            app.MetaValueListBox.Value = {};
            app.UpdatePlotButton = uibutton(app.UIFigure, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.GetadditionalaxesfromDropDownLabel = uilabel(app.UIFigure);
            app.GetadditionalaxesfromDropDownLabel.Text = 'Get additional axes from:';
            app.GetadditionalaxesfromDropDown = uidropdown(app.UIFigure);
            app.GetadditionalaxesfromDropDown.Items = {'None', 'Files', 'Objects', 'Signals', 'Dimension'};
            app.GetadditionalaxesfromDropDown.Value = 'None';
            app.DataTypeDropDownLabel = uilabel(app.UIFigure);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.UIFigure);
            app.DataTypeDropDown.Items = {'Raw', 'Processed', 'Results'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'Raw';
            app.v10Label = uilabel(app.UIFigure);
            app.v10Label.Text = 'v1.0';
            app.ExportPlotButton = uibutton(app.UIFigure, 'push');
            app.ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPlotButtonPushed, true);
            app.ExportPlotButton.Text = 'Export Plot';
            app.PlotensembleCheckBox = uicheckbox(app.UIFigure);
            app.PlotensembleCheckBox.Text = 'Plot ensemble';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = review_General(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end