classdef review_RAW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotselectedobjectsListBox      matlab.ui.control.ListBox
        PlotselectedobjectsListBoxLabel  matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        SelectplottypeDropDown          matlab.ui.control.DropDown
        SelectplottypeDropDownLabel     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        PlotselectdatadimensionListBox  matlab.ui.control.ListBox
        PlotselectdatadimensionListBoxLabel  matlab.ui.control.Label
        PlotselectedsignalsListBox      matlab.ui.control.ListBox
        PlotselectedsignalsListBoxLabel  matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        Log                             matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        RawDataReivewLabel              matlab.ui.control.Label
    end


    properties (Access = private)
        BAR_App   % Main app object
    end

    properties (Access = public)
    end

    methods (Access = private)

        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['selection not valid to plot'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end

        function UpdatePlot(app, dim)
            checkedNodes = app.Tree.CheckedNodes;

            app.Panel.Children.delete;

            data = getappdata(app.BAR_App.TableFiles, 'data');

            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'processed';
            end

            % This may solve some pixilation problems on computers when
            % displaying images.
            if strcmp(app.SelectplottypeDropDown.Value, 'Image')
                opengl hardware
            end

            switch app.SelectplottypeDropDown.Value
                case {'1D', 'Histogram'}
                    if isempty(dim)
                        dim = app.PlotselectdatadimensionListBox.Value;
                        if isempty(dim)
                            dim = 1;
                        else
                            dim = arrayfun(@str2double, dim);
                        end
                    end
                case '2D'
                    if isempty(dim)
                        dim = app.PlotselectdatadimensionListBox.Value;
                        if isempty(dim)
                            dim = [1; 2];
                        else
                            dim = arrayfun(@str2double, dim);
                        end
                        if length(dim) > 2
                            dim(3:end) = [];
                        end
                    end
                case '3D'
                    if isempty(dim)
                        dim = app.PlotselectdatadimensionListBox.Value;
                        if isempty(dim)
                            dim = [1; 2; 3];
                        else
                            dim = arrayfun(@str2double, dim);
                        end
                        if length(dim) > 3
                            dim(4:end) = [];
                        end
                    end
                case 'Image'
                    if isempty(dim)
                        dim = app.PlotselectdatadimensionListBox.Value;
                        if isempty(dim)
                            dim = 1;
                        end
                    end

            end

            plotData = [];
            for i = 1:length(dim)
                plotData{i} = [];
                ind = 1;
            end

            % A counter used for images.
            ind2 = 1;

            for i = 1:length(checkedNodes)

                n = 0;
                S = checkedNodes(i);
                while isprop(S,'Parent')
                    n = n + 1;
                    S = S.Parent;
                end

                if ~strcmp(app.SelectplottypeDropDown.Value, 'Image')

                    if n == 6

                        for j = 1:length(dim)

                            ind = size(plotData{j}, 2) + 1;

                            newData = data.(type).(checkedNodes(i).Parent.Parent.Text).(checkedNodes(i).Parent.Text).data.(checkedNodes(i).Text)(:,dim(j));
                            if ~isnumeric(newData)
                                printLog(app, '001')
                                return
                            end

                            if isempty(plotData{j})
                                plotData{j}(:, ind) = newData;
                                ind = ind + 1;
                            elseif size(newData, 1) > size(plotData{j}, 1)
                                plotData{j} = [plotData{j}; NaN*ones(size(newData, 1) - size(plotData{j}, 1), size(plotData{j}, 2))];
                                plotData{j}(:, ind) = newData;
                                ind = ind + 1;
                            elseif size(newData, 1) < size(plotData{j}, 1)
                                plotData{j}(:, ind) = [newData; NaN*ones(size(plotData{j}, 1) - size(newData, 1), 1)];
                                ind = ind + 1;
                            else
                                plotData{j}(:, ind) = newData;
                                ind = ind + 1;
                            end

                        end

                    end

                else

                    if ind2 > 6
                        break
                    end

                    switch n
                        case 6
                            plotData{ind2} = data.(type).(checkedNodes(i).Parent.Parent.Text).(checkedNodes(i).Parent.Text).data.(checkedNodes(i).Text);
                            ind2 = ind2 + 1;
                    end

                end

            end

            H = figure('visible', 'off');

            switch app.SelectplottypeDropDown.Value
                case '1D'
                    if  ind > 1
                        ax = axes('Parent', H);
                        for i = 1:length(plotData)
                            plot(ax, plotData{i}, 'k');
                            if i == 1
                                hold('on')
                            end
                            if i == length(plotData)
                                hold('off')
                                axis('tight')
                            end
                        end
                        copyobj(ax, app.Panel)
                    end
                case '2D'

                    if ind > 1

                        ax = axes('Parent', H);
                        plot(ax, plotData{1}, plotData{2}, 'k');
                        copyobj(ax, app.Panel)

                    end
                case '3D'

                    if ind > 1

                        ax = axes('Parent', H);
                        plot3(ax, plotData{1}, plotData{2}, plotData{3}, 'k');
                        copyobj(ax, app.Panel)

                    end

                case 'Image'
                    if length(plotData) == 1
                        ax = axes('Parent', H);
                        imshow(plotData{1}, 'Border','tight', 'Interpolation', 'bilinear');
                        copyobj(ax, app.Panel)
                    else
                        N = ceil(length(plotData)/2);
                        for i = 1:length(plotData)
                            ax(i) = subplot(N,2,i);
                            imshow(plotData{i}, 'Border','tight', 'Interpolation', 'bilinear')
                            copyobj(ax(i), app.Panel)
                        end
                    end
                case 'Histogram'
                    if  ind > 1
                        ax = axes('Parent', H);
                        for i = 1:length(plotData)
                            histogram(ax, plotData{i});
                            if i == 1
                                hold('on')
                            end
                            if i == length(plotData)
                                hold('off')
                                axis('tight')
                            end
                        end
                        copyobj(ax, app.Panel)
                    end
            end

        end

        function createNodeTree(app)

            app.Tree.Children.delete;

            data = getappdata(app.BAR_App.TableFiles, 'data');

            switch app.Switch.Value
                case 'Raw'
                    type = 'raw';
                case 'Processed'
                    type = 'processed';
            end

            allSigs = {'none'};
            allObjs = {'none'};

            files = fieldnames(data.(type));
            ind1 = 1;
            for i = 1:length(files)
                uitreenode(app.Tree, 'Text', files{i});
                objs = fieldnames(data.(type).(files{i}));
                allObjs = [allObjs; objs];
                ind2 = 1;
                for j = 1:length(objs)
                    if strcmp(objs{j}, 'meta')
                        continue
                    end
                    uitreenode(app.Tree.Children(ind1), 'Text', objs{j});
                    sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                    for k = 1:length(sigs)
                        uitreenode(app.Tree.Children(ind1).Children(ind2), 'Text', sigs{k});
                        allSigs = [allSigs; sigs{k}];
                    end
                    ind2 = ind2 + 1;
                end
                ind1 = ind1 + 1;
            end

            if numel(size(data.(type).(files{i}).(objs{j}).data.(sigs{k}))) == 2
                app.PlotselectdatadimensionListBox.Items = arrayfun(@num2str, (1:size(data.(type).(files{i}).(objs{j}).data.(sigs{k}), 2)), 'UniformOutput', 0);
            else
                app.PlotselectdatadimensionListBox.Items = {'1'};
            end

            app.PlotselectedsignalsListBox.Items = unique(allSigs);
            app.PlotselectedobjectsListBox.Items = unique(allObjs);
        end
    end

    methods (Access = public)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;

            config = loadConfig(app.BAR_App);

            app.SelectplottypeDropDown.Items = config.figures(1:2:end);

            createNodeTree(app)

        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)

            % Delete the dialog box
            delete(app)

        end

        % Callback function
        function SaveResultsandExitButtonPushed(app, event)

            delete(app)
        end

        % Value changed function: PlotselectedsignalsListBox
        function PlotselectedsignalsListBoxValueChanged(app, event)
            value1 = app.PlotselectedsignalsListBox.Value;

            app.Tree.CheckedNodes = [];

            for ii = 1:length(value1)

                if strcmp(value1{ii}, 'none')
                    app.Tree.CheckedNodes = [];
                    UpdatePlot(app, [])
                    return
                end

                data = getappdata(app.BAR_App.TableFiles, 'data');

                type = 'raw';

                files = fieldnames(data.(type));
                for i = 1:length(files)
                    objs = fieldnames(data.(type).(files{i}));
                    for j = 1:length(objs)
                        sigs = fieldnames(data.(type).(files{i}).(objs{j}).data);
                        for k = 1:length(sigs)
                            if contains(value1{ii}, sigs{k})
                                app.Tree.CheckedNodes = [app.Tree.CheckedNodes(:); app.Tree.Children(i).Children(j).Children(k)];
                            end
                        end
                    end
                end

            end

            UpdatePlot(app, [])

        end

        % Callback function: PlotselectdatadimensionListBox, 
        % SelectplottypeDropDown, Tree
        function UpdatePlotCallback(app, event)
            UpdatePlot(app, [])
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            createNodeTree(app)

            app.Panel.Children.delete

        end

        % Value changed function: PlotselectedobjectsListBox
        function PlotselectedobjectsListBoxValueChanged(app, event)
            value = app.PlotselectedobjectsListBox.Value;

            app.Tree.CheckedNodes = [];
            
            for ii = 1:length(value)

                if strcmp(value{ii}, 'none')
                    app.Tree.CheckedNodes = [];
                    UpdatePlot(app, [])
                    return
                end

                data = getappdata(app.BAR_App.TableFiles, 'data');

                type = 'raw';

                files = fieldnames(data.(type));
                for i = 1:length(files)
                    objs = fieldnames(data.(type).(files{i}));
                    for j = 1:length(objs)
                        if contains(value{ii}, objs{j})
                            app.Tree.CheckedNodes = [app.Tree.CheckedNodes(:); app.Tree.Children(i).Children(j)];
                        end
                    end
                end

            end

            UpdatePlot(app, [])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.RawDataReivewLabel = uilabel(app.UIFigure);
            app.RawDataReivewLabel.Text = 'Raw Data Reivew';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.Log = uitextarea(app.UIFigure);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @UpdatePlotCallback, true);
            app.PlotselectedsignalsListBoxLabel = uilabel(app.UIFigure);
            app.PlotselectedsignalsListBoxLabel.Text = 'Plot selected signals';
            app.PlotselectedsignalsListBox = uilistbox(app.UIFigure);
            app.PlotselectedsignalsListBox.Items = {};
            app.PlotselectedsignalsListBox.Multiselect = 'on';
            app.PlotselectedsignalsListBox.ValueChangedFcn = createCallbackFcn(app, @PlotselectedsignalsListBoxValueChanged, true);
            app.PlotselectedsignalsListBox.Value = {};
            app.PlotselectdatadimensionListBoxLabel = uilabel(app.UIFigure);
            app.PlotselectdatadimensionListBoxLabel.Text = 'Plot select data dimension';
            app.PlotselectdatadimensionListBox = uilistbox(app.UIFigure);
            app.PlotselectdatadimensionListBox.Items = {};
            app.PlotselectdatadimensionListBox.Multiselect = 'on';
            app.PlotselectdatadimensionListBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotCallback, true);
            app.PlotselectdatadimensionListBox.Value = {};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.SelectplottypeDropDownLabel = uilabel(app.UIFigure);
            app.SelectplottypeDropDownLabel.Text = 'Select plot type';
            app.SelectplottypeDropDown = uidropdown(app.UIFigure);
            app.SelectplottypeDropDown.Items = {};
            app.SelectplottypeDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdatePlotCallback, true);
            app.SelectplottypeDropDown.Value = {};
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Raw', 'Processed'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Raw';
            app.PlotselectedobjectsListBoxLabel = uilabel(app.UIFigure);
            app.PlotselectedobjectsListBoxLabel.Text = 'Plot selected objects';
            app.PlotselectedobjectsListBox = uilistbox(app.UIFigure);
            app.PlotselectedobjectsListBox.Items = {};
            app.PlotselectedobjectsListBox.Multiselect = 'on';
            app.PlotselectedobjectsListBox.ValueChangedFcn = createCallbackFcn(app, @PlotselectedobjectsListBoxValueChanged, true);
            app.PlotselectedobjectsListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = review_RAW(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end