classdef review_TimeLag < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotselectsignalsListBox       matlab.ui.control.ListBox
        PlotselectsignalsListBoxLabel  matlab.ui.control.Label
        SelectmissingresultsCheckBox   matlab.ui.control.CheckBox
        PlotensembleCheckBox           matlab.ui.control.CheckBox
        Plot15thvalueblueCheckBox      matlab.ui.control.CheckBox
        PlotfirstminimumsredCheckBox   matlab.ui.control.CheckBox
        PlotallminimumsblackCheckBox   matlab.ui.control.CheckBox
        Tree                           matlab.ui.container.CheckBoxTree
        Log                            matlab.ui.control.TextArea
        SelectDataDimensionSpinner     matlab.ui.control.Spinner
        SelectDataDimensionSpinnerLabel  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        TimeLagReviewLabel             matlab.ui.control.Label
        FigureResults                  matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        BAR_App   % Main app object
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function printLog(app, code, newText)
            text = app.Log.Value;
            switch code
                case '001'
                    text{length(text) + 1,1} = ['selection not valid to plot'];
            end
            app.Log.Value = text;
            scroll(app.Log,'bottom')
            drawnow
        end
        
        
    end
    
    methods (Access = public)
        
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, data)
            % Store main app in property for CloseRequestFcn to use
            app.BAR_App = mainapp;

            config = loadConfig(app.BAR_App);
            
            setappdata(app.UIFigure, 'data', data)

            type = 'res';
            review = 'TimeLag';

            allSigs = {'none'};

            files = fieldnames(data.(type).(review));
            ind1 = 1;
            for i = 1:length(files)
                uitreenode(app.Tree, 'Text', files{i});
                objs = fieldnames(data.(type).(review).(files{i}));
                ind2 = 1;
                for j = 1:length(objs)
                    uitreenode(app.Tree.Children(ind1), 'Text', objs{j})
                    sigs = fieldnames(data.(type).(review).(files{i}).(objs{j}));
                    allSigs = [allSigs; sigs];
                    ind3 = 1;
                    for k = 1:length(sigs)
                        uitreenode(app.Tree.Children(ind1).Children(ind2), 'Text', sigs{k});
                        ind3 = ind3 + 1;
                    end
                    ind2 = ind2 + 1;
                end
                ind1 = ind1 + 1;
            end

            app.SelectDataDimensionSpinner.Limits = [1, numel(data.(type).(review).(files{i}).(objs{j}).(sigs{k}).ami)];

            app.PlotselectsignalsListBox.Items = unique(allSigs);
            
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Callback function
        function SaveResultsandExitButtonPushed(app, event)
            data = getappdata(app.UIFigure, 'data');
            analysis = app.TimeLagReviewLabel.Text;
            
            analysisComplete(app.BAR_App, data, analysis)

            delete(app)
        end

        % Callback function: Plot15thvalueblueCheckBox, 
        % PlotallminimumsblackCheckBox, PlotensembleCheckBox, 
        % PlotfirstminimumsredCheckBox, SelectDataDimensionSpinner, Tree
        function UpdatePlot(app, event)
            checkedNodes = app.Tree.CheckedNodes;

            cla(app.FigureResults)
            
            data = getappdata(app.UIFigure, 'data');
            
            type = 'res';
            review = 'TimeLag';

            dim = app.SelectDataDimensionSpinner.Value;

            plotData = [];
            plotMinimumsAll = [];
            plotMinimumsFirst = [];
            plotMinimums1Fifth = [];

            ind = 1;

            for i = 1:length(checkedNodes)

                n = 0;
                S = checkedNodes(i);
                while isprop(S,'Parent')
                    n = n + 1;
                    S = S.Parent;
                end

                if n == 6
                    plotData(:, ind) = data.(type).(review).(checkedNodes(i).Parent.Parent.Text).(checkedNodes(i).Parent.Text).(checkedNodes(i).Text).ami{dim}(:,2);
                    ind = ind + 1;

                    if app.PlotallminimumsblackCheckBox.Value == 1
                        plotMinimumsAll = [plotMinimumsAll; data.(type).(review).(checkedNodes(i).Parent.Parent.Text).(checkedNodes(i).Parent.Text).(checkedNodes(i).Text).tau1st{dim}];
                    end
                    if app.PlotfirstminimumsredCheckBox.Value == 1
                        plotMinimumsFirst = [plotMinimumsFirst; data.(type).(review).(checkedNodes(i).Parent.Parent.Text).(checkedNodes(i).Parent.Text).(checkedNodes(i).Text).tau1st{dim}(1, :)];
                    end
                    if app.Plot15thvalueblueCheckBox.Value == 1
                        plotMinimums1Fifth = [plotMinimums1Fifth; data.(type).(review).(checkedNodes(i).Parent.Parent.Text).(checkedNodes(i).Parent.Text).(checkedNodes(i).Text).tau15th{dim}(end, :)];
                    end
                    
                end

            end

            if app.PlotensembleCheckBox.Value == 1
                
                fill(app.FigureResults, [1:size(plotData, 1), size(plotData, 1):-1:1]', [mean(plotData, 2, 'omitnan') - std(plotData, [], 2, 'omitnan'); flipud(mean(plotData, 2, 'omitnan') + std(plotData, [], 2, 'omitnan'))], [0.7, 0.7, 0.7], 'LineStyle', 'none')
                hold(app.FigureResults, 'on')
                plot(app.FigureResults, mean(plotData, 2), 'k')
                axis(app.FigureResults, 'tight')

                if app.PlotfirstminimumsredCheckBox.Value == 1
                    plot(app.FigureResults, mean(plotMinimumsFirst(:,1), 'omitnan'), mean(plotMinimumsFirst(:,2), 'omitnan'), 'ro')
                end
                if app.Plot15thvalueblueCheckBox.Value == 1
                    plot(app.FigureResults, mean(plotMinimums1Fifth(:,1), 'omitnan'), mean(plotMinimums1Fifth(:,2), 'omitnan') ,'bo')
                end

                hold(app.FigureResults, 'off')

            else

                plot(app.FigureResults, plotData, 'k')
                hold(app.FigureResults, 'on')
                if app.PlotallminimumsblackCheckBox.Value == 1
                    plot(app.FigureResults, plotMinimumsAll(:,1), plotMinimumsAll(:,2), 'k.')
                end
                if app.PlotfirstminimumsredCheckBox.Value == 1
                    plot(app.FigureResults, plotMinimumsFirst(:,1), plotMinimumsFirst(:,2), 'ro')
                end
                if app.Plot15thvalueblueCheckBox.Value == 1
                    plot(app.FigureResults, plotMinimums1Fifth(:,1), plotMinimums1Fifth(:,2) ,'bo')
                end
                hold(app.FigureResults, 'off')
                axis(app.FigureResults, 'tight')

            end

        end

        % Value changed function: SelectmissingresultsCheckBox
        function SelectmissingresultsCheckBoxValueChanged(app, event)
            value = app.SelectmissingresultsCheckBox.Value;

            if value

                dim = app.SelectDataDimensionSpinner.Value;

                data = getappdata(app.UIFigure, 'data');

                type = 'res';
                review = 'TimeLag';

                files = fieldnames(data.(type).(review));
                ind1 = 1;
                for i = 1:length(files)
                    objs = fieldnames(data.(type).(review).(files{i}));
                    ind2 = 1;
                    for j = 1:length(objs)
                        sigs = fieldnames(data.(type).(review).(files{i}).(objs{j}));
                        ind3 = 1;
                        for k = 1:length(sigs)
                            tau1st = data.(type).(review).(files{i}).(objs{j}).(sigs{k}).tau1st{dim};
                            tau15th = data.(type).(review).(files{i}).(objs{j}).(sigs{k}).tau15th{dim};
                            if any(isnan(tau1st), 'all') || any(isnan(tau15th), 'all')
                                app.Tree.CheckedNodes = [app.Tree.CheckedNodes(:); app.Tree.Children(i).Children(j).Children(k)];
                            end
                            ind3 = ind3 + 1;
                        end
                        ind2 = ind2 + 1;
                    end
                    ind1 = ind1 + 1;
                end

                app.UpdatePlot

            end

        end

        % Value changed function: PlotselectsignalsListBox
        function PlotselectsignalsListBoxValueChanged(app, event)
            value = app.PlotselectsignalsListBox.Value;

            for ii = 1:length(value)

                if strcmp(value{ii}, 'none')
                    app.Tree.CheckedNodes = [];
                    app.UpdatePlot
                    return
                end

                data = getappdata(app.UIFigure, 'data');

                type = 'res';
                review = 'TimeLag';

                files = fieldnames(data.(type).(review));
                for i = 1:length(files)
                    objs = fieldnames(data.(type).(review).(files{i}));
                    for j = 1:length(objs)
                        sigs = fieldnames(data.(type).(review).(files{i}).(objs{j}));
                        for k = 1:length(sigs)
                            if strcmp(sigs{k}, value{ii})
                                app.Tree.CheckedNodes = [app.Tree.CheckedNodes(:); app.Tree.Children(i).Children(j).Children(k)];
                            end
                        end
                    end
                end

            end

            app.UpdatePlot
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.FigureResults = uiaxes(app.UIFigure);
            xlabel(app.FigureResults, 'Time Lag')
            ylabel(app.FigureResults, 'AMI')
            app.TimeLagReviewLabel = uilabel(app.UIFigure);
            app.TimeLagReviewLabel.Text = 'Time Lag Review';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo_122116.png';
            app.SelectDataDimensionSpinnerLabel = uilabel(app.UIFigure);
            app.SelectDataDimensionSpinnerLabel.Text = 'Select Data Dimension';
            app.SelectDataDimensionSpinner = uispinner(app.UIFigure);
            app.SelectDataDimensionSpinner.Limits = [1 1000000];
            app.SelectDataDimensionSpinner.RoundFractionalValues = 'on';
            app.SelectDataDimensionSpinner.ValueDisplayFormat = '%11.0g';
            app.SelectDataDimensionSpinner.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
            app.SelectDataDimensionSpinner.Value = 1;
            app.Log = uitextarea(app.UIFigure);
            app.Tree = uitree(app.UIFigure, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
            app.PlotallminimumsblackCheckBox = uicheckbox(app.UIFigure);
            app.PlotallminimumsblackCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
            app.PlotallminimumsblackCheckBox.Text = 'Plot all minimums (black)';
            app.PlotfirstminimumsredCheckBox = uicheckbox(app.UIFigure);
            app.PlotfirstminimumsredCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
            app.PlotfirstminimumsredCheckBox.Text = 'Plot first minimums (red)';
            app.Plot15thvalueblueCheckBox = uicheckbox(app.UIFigure);
            app.Plot15thvalueblueCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
            app.Plot15thvalueblueCheckBox.Text = 'Plot 1/5th value (blue)';
            app.PlotensembleCheckBox = uicheckbox(app.UIFigure);
            app.PlotensembleCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdatePlot, true);
            app.PlotensembleCheckBox.Text = 'Plot ensemble';
            app.SelectmissingresultsCheckBox = uicheckbox(app.UIFigure);
            app.SelectmissingresultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectmissingresultsCheckBoxValueChanged, true);
            app.SelectmissingresultsCheckBox.Text = 'Select missing results';
            app.PlotselectsignalsListBoxLabel = uilabel(app.UIFigure);
            app.PlotselectsignalsListBoxLabel.Text = 'Plot select signals';
            app.PlotselectsignalsListBox = uilistbox(app.UIFigure);
            app.PlotselectsignalsListBox.Items = {};
            app.PlotselectsignalsListBox.Multiselect = 'on';
            app.PlotselectsignalsListBox.ValueChangedFcn = createCallbackFcn(app, @PlotselectsignalsListBoxValueChanged, true);
            app.PlotselectsignalsListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = review_TimeLag(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end